classdef createNodeStandard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ui_createNodeStandard          matlab.ui.Figure
        tabGroup                       matlab.ui.container.TabGroup
        tab_start                      matlab.ui.container.Tab
        panel_start                    matlab.ui.container.Panel
        label_targetDir                matlab.ui.control.Label
        button_create                  matlab.ui.control.Button
        button_changeDir               matlab.ui.control.Button
        SaveAsEditFieldLabel           matlab.ui.control.Label
        editF_saveAs                   matlab.ui.control.EditField
        tab_nodes                      matlab.ui.container.Tab
        panel_nodes                    matlab.ui.container.Panel
        table_recordedNodes            matlab.ui.control.Table
        button_make                    matlab.ui.control.Button
        button_deleteSelection         matlab.ui.control.Button
        button_uploadImage             matlab.ui.control.Button
        uia_image                      matlab.ui.control.UIAxes
        button_openImage               matlab.ui.control.Button
        button_addRow                  matlab.ui.control.Button
        label_tableExplanation         matlab.ui.control.Label
        NodeCoordinatesListLabel       matlab.ui.control.Label
        textA_nodeList                 matlab.ui.control.TextArea
        tab_nodeGraph                  matlab.ui.container.Tab
        panel_nodeGraphMap             matlab.ui.container.Panel
        table_adjacencyMatrix          matlab.ui.control.Table
        button_makeAdjacencyMatrix     matlab.ui.control.Button
        ResizeColWidthsSpinnerLabel    matlab.ui.control.Label
        spinner_resizeColWidths        matlab.ui.control.Spinner
        button_makeGraph               matlab.ui.control.Button
        button_openGraphvsImage        matlab.ui.control.Button
        axes_nodeGraph                 matlab.ui.control.UIAxes
        tab_nameEdges                  matlab.ui.container.Tab
        panel_nameEdges                matlab.ui.container.Panel
        table_edgeNames                matlab.ui.control.Table
        table_nodeTypes                matlab.ui.control.Table
        button_populateEdgeNodeTables  matlab.ui.control.Button
        button_doneFillEmptySpaces     matlab.ui.control.Button
        tab_MakeBodyKinematics         matlab.ui.container.Tab
        panel_makeBodyKinematics       matlab.ui.container.Panel
        button_createBodyKinematics    matlab.ui.control.Button
        button_saveAndFinish           matlab.ui.control.Button
        uia_bodyGraph                  matlab.ui.control.UIAxes
        button_prev                    matlab.ui.control.Button
        button_next                    matlab.ui.control.Button
        Label_errorMsg                 matlab.ui.control.Label
    end


    properties (Access = private)
        % root drectory for Node Standards
        %TODO -- Change this to Desktop
        rootDir = '';
        
        % used to transfer values from user entry to Node table
        % a value of 0 is no cells selected
        selectedTableNodes = 0;
        
        % used to append characters from user entry prior to storage in Node table
        emptyRow = {'' false false false false false}
        rightChar   =  'R';
        leftChar    =  'L';
        xCoordinate = '_X';
        yCoordinate = '_Y';
        zCoordinate = '_Z';
        
        %image for reference location and name
        imageChangeFlag = 0;
        imageFile = '';
        prevImageFile = '';
        imagePath = '';
        
        % tab control
        tc = 1;

    end
    
    properties (Access = public)
        appName = 'createNodeStandard';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
        
        % conectivty graph
        conGraph;
        adjMatrix;
        
        % store values for Nodes and their coordinate expansions
        stdName;        % name of this standard
        nodes;          % nodes in standard
        nodeComponents; % nodes in standard expanded with XYZ coordiantes
        image;          % reference image for standard
        imageName;
    end
    
    methods (Access = private)
%         % make sure that savingDir contains no directory with the same name.
%         function k = doesDirExsist(app)
%             k = 0;
%             tdirs = cellstr(ls(app.rootDir));
%             for i = 1:size(tdirs,1)
%                 if(strcmp(tdirs(i), char(app.editF_saveAs.Value)))
%                     k = 1;
%                 end
%             end
%         end%func doesDirExsist
    
        
%         function updateTargetDir(app)
%             app.label_targetDir.Text = fullfile(app.rootDir, app.editF_saveAs.Value);
%         end
    end%methods
       

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % start array with one empty line
            button_addRowButtonPushed(app);
            
            %TODO -- is pwd always at biolocomotion when starting?
            %TODO -- what about if the app is launched from an exe
            %TODO -- is prob best if it saves in Host's home, for now save in ./res
            app.label_targetDir.Text = app.rootDir;
            
            
            % fist turn all tabs off
            %TODO -- this is hardcoded, create a struct withthe same tab
            app.panel_start.Visible = 'on';
            app.panel_nodes.Visible = 'off';
            app.panel_nodeGraphMap.Visible = 'off';
            app.panel_nameEdges.Visible = 'off';
            app.panel_makeBodyKinematics.Visible = 'off';
            
            % set a default value to root Directory
            %TODO -- ideally this should be in the users HOME, but seems not to be easy
            app.label_targetDir.Text = app.rootDir;
           
        end

        % Button pushed function: button_make
        function button_makeButtonPushed(app, event)
            %TODO -- i am not specifyign the variable type of either of these arrays, is that ok?
            %TODO -- i am not checking if node list is empty. though it seems to be file
            %TODO -- i am not trimming witespaces
            %TODO -- create a label with the number of nodes that exist currently in recorded nodes table
            %TODO -- need to check that the fields are not empty
            
            % first delete empty cells
            tmp = strcmp(app.table_recordedNodes.Data(:,1), '');
            app.table_recordedNodes.Data = app.table_recordedNodes.Data(~tmp,:);
            
            % check that matrix is not empty
            if(isempty(app.table_recordedNodes.Data))
                % if it is empty then insert one space
                button_addRowButtonPushed(app);
                app.Label_errorMsg.Text = 'ERROR: Marix is empty.';
                
            % then make sure all items are unique
            % if the are not unique, display error
            elseif(size(app.table_recordedNodes.Data(:,1),1) ~= size(unique(app.table_recordedNodes.Data(:,1)),1))
                app.Label_errorMsg.Text = 'ERROR: values are not unique!';
            
            % if they are unique then proceed
            else
                % if there was an error, clear it
                app.Label_errorMsg.Text = '';
                
                % assign values
                tnodes = app.table_recordedNodes.Data(:,1);
                tright = logical(cell2mat(app.table_recordedNodes.Data(:,2)));
                tleft  = logical(cell2mat(app.table_recordedNodes.Data(:,3)));
                tcordx = logical(cell2mat(app.table_recordedNodes.Data(:,4)));
                tcordy = logical(cell2mat(app.table_recordedNodes.Data(:,5)));
                tcordz = logical(cell2mat(app.table_recordedNodes.Data(:,6)));
                
                app.nodes = {};
                app.nodeComponents = {};
                
                % needed for matrix Xcord expansions
                tcnt = 0;
                
                % first create the nodes, if R or L is selcted then create those
                % if neither R nor L is selected, then copy the vaue as-is
                % if both RL are true, then expand tcordX also
                % if RL have different coordinate then nodes should be enetred separatelly
                % one checking R and the other L with their coordinates
                %TODO -- this will not allow RL to have different coordinates
                for(i = 1: size(tnodes,1))
                    if(tright(i))
                        tmp = [char(tnodes(i)) app.rightChar];
                        app.nodes = [app.nodes; tmp];
                    end
                    if(tleft(i))
                        tmp = [char(tnodes(i)) app.leftChar];
                        app.nodes = [app.nodes; tmp];
                    end
                    if((~tright(i)) && (~tleft(i)))
                        tmp = char(tnodes(i));
                        app.nodes = [app.nodes; tmp];
                    end
                    if(tright(i) && tleft(i))
                        % this matrix gets bigger, so i needs to be increased every time (tcnt) this gets exc
                        k = i+tcnt;
                        tcordx = [tcordx(1:k); tcordx(k); tcordx((k+1):end)];
                        tcordy = [tcordy(1:k); tcordy(k); tcordy((k+1):end)];
                        tcordz = [tcordz(1:k); tcordz(k); tcordz((k+1):end)];
                        tcnt = tcnt +1;
                    end        
                end%for RL
                
                % each node can be associated with a cooridinate component from recordings
                %TODO -- what if a node is just itself no RLXYZ? what does that mean?
                %TODO -- first check that all names are unique after RL
                for(i = 1: size(app.nodes,1))
                    if(tcordx(i))
                        tmp = [char(app.nodes(i)) app.xCoordinate];
                        app.nodeComponents = [app.nodeComponents; tmp];
                    end
                    if(tcordy(i))
                        tmp = [char(app.nodes(i)) app.yCoordinate];
                        app.nodeComponents = [app.nodeComponents; tmp];
                    end
                    if(tcordz(i))
                        tmp = [char(app.nodes(i)) app.zCoordinate];
                        app.nodeComponents = [app.nodeComponents; tmp];
                    end
                    %TODO -- I dont know what this represents
                    if(~tcordx(i) && ~tcordy(i) && ~tcordz(i))
                        app.nodeComponents = [app.nodeComponents; char(app.nodes(i))];
                    end
                end%for XYZ
                
                % display the results in the text area
                app.textA_nodeList.Value = app.nodeComponents;

            end%if values are unique
                

        end

        % Button pushed function: button_deleteSelection
        function button_deleteSelectionPushed(app, event)
            % cherry-pick values from table and remove them
            % user needs to make a selection first, then press
            % if nothing is selected then selected nodes is zero, that is not a valid index;
            % also check if matrix is empty already
            % cells selected array comes from an ISR table_recordedNodesCellSelection()

            if(~isempty(app.selectedTableNodes(:,1)) && app.selectedTableNodes ~= 0)
                app.Label_errorMsg.Text = '';
                app.table_recordedNodes.Data(app.selectedTableNodes(:,1),:) = [];
                app.selectedTableNodes = 0;
            else
                app.Label_errorMsg.Text = 'ERROR: Select at least one cell';
            end
        end

        % Cell selection callback: table_recordedNodes
        function table_recordedNodesCellSelection(app, event)
           % for some reason it does not like to be directly assigned to private var
           % indices is probably adefined attribute of this function.
            indices = event.Indices(:,1);
           app.selectedTableNodes = indices;
        end

        % Button pushed function: button_uploadImage
        function button_uploadImageButtonPushed(app, event)
            %TODO -- needs to be wraped in try-catch
            %TODO -- make sure file selected is less than 500kb
            %TODO -- this may be different for unix systems
            %s = dir([path file]);
            %if (s.bytes < 500000)
            
            [app.imageFile,app.imagePath] = uigetfile('*png', 'Select a PNG image (500kB max)');
            app.button_openImage.Enable = 'on';
            
            % this is used for saving the image just once
            % copy even if it is the same image again.
            %TODO -- do I need full path here in other platforms?
            %TODO -- save image in private variable
            app.imageChangeFlag = 1;
            tmp = imread([app.imagePath app.imageFile]);
            
            %display image on app
            imshow(tmp, 'parent', app.uia_image);
            
        end

        % Button pushed function: button_create
        function button_createPushed(app, event)
            %TODO -- format input text to make sure it follows rules
            
            if(isempty(app.rootDir))
                app.Label_errorMsg.Text = 'A propper directory must be selected first!';
            elseif(isempty(app.editF_saveAs.Value))
                app.Label_errorMsg.Text = 'Name cannot be Empty!';
            elseif(~isempty(dir([app.rootDir '\' app.editF_saveAs.Value '.mat'])))
                app.Label_errorMsg.Text = 'There is a file with that name here already';
            else
                % disable and lock fields
                app.Label_errorMsg.Text    = '';
                app.editF_saveAs.Enable     = 'off';
                app.button_create.Enable    = 'off';
                app.button_changeDir.Enable = 'off';
                
                % name the app
                app.stdName = app.editF_saveAs.Value;
            end
            
        end

        % Button pushed function: button_changeDir
        function button_changeDirPushed(app, event)
            %TODO -- this should default to the users desktop
            %TODO -- for some reason this sometimes crashes if it is on my first screen only??
            try
                app.rootDir = uigetdir('Select annother target directory');
                app.label_targetDir.Text = app.rootDir;
            catch
                msgbox('You must select a directory!', 'Chaos!', 'warn');
            end
            
        end

        % Button pushed function: button_openImage
        function button_openImageButtonPushed(app, event)
            % the button is enabled by loading an image, then display this to zoom
            imshow([app.imagePath app.imageFile]);
        end

        % Button pushed function: button_addRow
        function button_addRowButtonPushed(app, event)
            app.table_recordedNodes.Data = [app.table_recordedNodes.Data; app.emptyRow]; 
        end

        % Button pushed function: button_makeAdjacencyMatrix
        function button_makeAdjacencyMatrixButtonPushed(app, event)
            % pupulate the table with Node Standard Names
            % first get the values to name the columns and rows
            %TODO -- if data is empty this might crash
            
            % initialize the table with empty values
            %TODO -- first check that matrix is not empty?
            app.table_adjacencyMatrix.Data = false(size(app.nodes,1));
            
            % allow user to modify table
            app.table_adjacencyMatrix.ColumnEditable = true;
            
            % make table a checkbox one
            % TODO -- this format is applying it to the whole table
            tmpcell = cell(1, size(app.nodes,1));
            tmpcell(1:end) = cellstr('logical');
            app.table_adjacencyMatrix.ColumnFormat = tmpcell;
            
            % format width with the value of spinner
            tcolsize = cell(1, size(app.nodes,1));
            tcolsize(1:end) = num2cell(app.spinner_resizeColWidths.Value);
            app.table_adjacencyMatrix.ColumnWidth = tcolsize;
            
            % include names of columns and rows
            app.table_adjacencyMatrix.ColumnName = app.nodes;
            app.table_adjacencyMatrix.RowName    = app.nodes;
            
            % display table
            app.table_adjacencyMatrix.Visible = 'on';

        end

        % Value changed function: spinner_resizeColWidths
        function spinner_resizeColWidthsValueChanged(app, event)
            value = app.spinner_resizeColWidths.Value;
            
            % format width as value from spinner field
            tcolsize = cell(1, size(app.nodes,1));
            tcolsize(1:end) = num2cell(value);
            app.table_adjacencyMatrix.ColumnWidth = tcolsize;
            
        end

        % Button pushed function: button_makeGraph
        function button_makeGraphButtonPushed(app, event)
            % make a connectiviy graph rom adj matrix
            %TODO -- if matrix is empty it may crash
            
            % first clear diagonal and lower half
            %TODO -- display a message that this will happen and put instructions in text too
            tmp = logical(triu(ones(size(app.nodes,1)))-eye(size(app.nodes,1)));
            app.table_adjacencyMatrix.Data = tmp&app.table_adjacencyMatrix.Data;
            
            app.adjMatrix = app.table_adjacencyMatrix.Data | transpose(app.table_adjacencyMatrix.Data);
            
            app.conGraph = graph(app.adjMatrix, app.nodes);
            
            plot(app.conGraph, 'parent', app.axes_nodeGraph);
            
        end

        % Button pushed function: button_populateEdgeNodeTables
        function button_populateEdgeNodeTablesButtonPushed(app, event)
            %TODO -- i am using app.nodes but should i use app.congraph.nodes?
            
            % make table a cell one
            tmpcell = cell(size(app.conGraph.Edges.EndNodes,1),1);
            tmpcell(1:end) = {''};
            app.table_edgeNames.Data = tmpcell;
            
            % populate the table of edges.
            app.table_edgeNames.Data = app.conGraph.Edges.EndNodes(:,1);
            app.table_edgeNames.Data(:,2) = app.conGraph.Edges.EndNodes(:,2);
            tmpcell = cell(1, size(app.table_edgeNames.Data,1));
            tmpcell(1:end) = {''};
            app.table_edgeNames.Data(:,3) = tmpcell;
            
            % populate the table of node types
            %TODO -- this irst line?
            %app.conGraph.Nodes.Names = app.nodes;
            app.table_nodeTypes.Data = app.conGraph.Nodes.Name;
            % second colum is a drop down for type of node
            tmpcell = cell(size(app.table_nodeTypes.Data,1),1);
            tmpcell(1:end) = {'type:'};
            app.table_nodeTypes.Data = [app.table_nodeTypes.Data tmpcell];
            % thid column is logical to find who is the origin
            tmpcell = cell(size(app.table_nodeTypes.Data,1),1);
            tmpcell(1:end) = {false};
            app.table_nodeTypes.Data = [app.table_nodeTypes.Data tmpcell];
            
            
            % make table an option one
            %TODO -- there can only be one origin add a column for logical
            app.table_nodeTypes.ColumnFormat = {[] {'node' 'joint'} 'logical'};
        end

        % Button pushed function: button_doneFillEmptySpaces
        function button_doneFillEmptySpacesPushed(app, event)
            
            %TODO -- all tables Data could be referenced b colname too!
            %TODO -- all names must be unique
            %TODO -- only one origin should be permitted
            if(any(strcmp(app.table_nodeTypes.Data(:,2),'type:')))
                % then send a warning that all should be declared
                %TODO -- this did not work
                msgbox('All items in Node Table should be declared', 'Chaos!', 'warn');
                
            else
                % assign a default edge name to the ones not filled
                % a default of node1_node2
                tmp = strcmp(app.table_edgeNames.Data(:,3), '');
                app.table_edgeNames.Data(tmp,3) = strcat(app.table_edgeNames.Data(tmp,1), '_', app.table_edgeNames.Data(tmp,2));
                % Then upadate the edges table variable with all this
                app.conGraph.Edges.Names = app.table_edgeNames.Data(:,3);
                
                % append to the variable nodes the names
                %TODO -- ad originto table
                %TODO -- this is not working
                app.tab
                app.conGraph.Nodes.Type = app.table_nodeTypes(:,2);
            end
            
        end

        % Button pushed function: button_createBodyKinematics
        function button_createBodyKinematicsButtonPushed(app, event)
            % plot the new and complete conectivity graph
            plot(app.conGraph, 'parent', app.uia_bodyGraph);
            save(fullfile('C:\Users\alberto-bortoni\Desktop\sandbox\', [app.editF_saveAs.Value '.mat']));
            % create joints
            %TODO -- in joint file include type joint, node or origin
            %TODO -- in joint file create instanses numbr from edge list and name of instance by next joint? by link name?


        end

        % Button pushed function: button_next
        function button_nextButtonPushed(app, event)
            
            % fist turn all tabs off
            %TODO -- this is hardcoded, create a struct withthe same tab
            app.panel_start.Visible = 'off';
            app.panel_nodes.Visible = 'off';
            app.panel_nodeGraphMap.Visible = 'off';
            app.panel_nameEdges.Visible = 'off';
            app.panel_makeBodyKinematics.Visible = 'off';
            
            % increment tab control if no bigger than size of tab
            if(app.tc < size(app.tabGroup.Children,1))
                app.tc = app.tc+1;
            end
            
            % activate appropiate panel
            switch(app.tc)
                case 1;
                    app.panel_start.Visible = 'on';
                case 2;
                    app.panel_nodes.Visible = 'on';
                case 3;
                    app.panel_nodeGraphMap.Visible = 'on';
                case 4;
                    app.panel_nameEdges.Visible = 'on';
                case 5;
                    app.panel_makeBodyKinematics.Visible = 'on';
            end
            
            % activate appropiate tab
            app.tabGroup.SelectedTab = app.tabGroup.Children(app.tc);
            
        end

        % Button pushed function: button_prev
        function button_prevButtonPushed(app, event)
                        
            % fist turn all tabs off
            %TODO -- this is hardcoded, create a struct withthe same tab
            app.panel_start.Visible = 'off';
            app.panel_nodes.Visible = 'off';
            app.panel_nodeGraphMap.Visible = 'off';
            app.panel_nameEdges.Visible = 'off';
            app.panel_makeBodyKinematics.Visible = 'off';
            
            % increment tab control if no bigger than size of tab
            if(app.tc > 1)
                app.tc = app.tc-1;
            end
            
            % activate appropiate panel
            switch(app.tc)
                case 1;
                    app.panel_start.Visible = 'on';
                case 2;
                    app.panel_nodes.Visible = 'on';
                case 3;
                    app.panel_nodeGraphMap.Visible = 'on';
                case 4;
                    app.panel_nameEdges.Visible = 'on';
                case 5;
                    app.panel_makeBodyKinematics.Visible = 'on';
            end
            
            % activate appropiate tab
            app.tabGroup.SelectedTab = app.tabGroup.Children(app.tc);
        end

        % Button pushed function: button_saveAndFinish
        function button_saveAndFinishButtonPushed(app, event)
            %TODO -- trim image name so it does not have spaces
            %TODO -- format input text to make sure it follows rules

            % if this is the first time user hits create
            if(app.createdFlag == 0)
                if(size(app.editF_saveAs.Value, 1) == 0)
                    app.Label_errorMsg.Text = 'Name cannot be Empty';
                %TODO -- this only checks the first time, after creation, if i change rootDir this has no effect
                elseif(doesDirExsist(app) == 1)
                    app.Label_errorMsg.Text = 'There is a directory with that name here already';
                else
                    app.Label_errorMsg.Text = '';
                    app.editF_saveAs.Enable = 'off';
                    app.button_create.Text = 'Save All';
                    app.panel_nodes.Visible = 'on';
                    % next time skip this and instead create the directory
                    app.createdFlag = 1;
                    % update the save directory text
                    updateTargetDir(app);
                end
                
            % if user hits save, store all valuable variables.
            else
                %make sure you create directory only once
                if(app.createdFlag == 1)           
                    % create a directory with the same name as the file to save
                    %TODO -- this needs a try catch make soft make dir
                    mkdir(app.label_targetDir.Text);
                    % now that dir has been created, disable chaning it
                    app.button_changeDir.Enable = 'off';
                    % make sure you dont return here
                    app.createdFlag = 2;
                end
                    
                %variables to store
                %TODO -- these are now incorrectly labled
                stdNodeNames = app.table_recordedNodes.Data;
                stdName = char(app.editF_saveAs.Value);
                stdImageName = app.imageFile;
                
                % only save image if user has selected a different one
                if(app.imageChangeFlag == 1)
                    % delete prev image as to keep just one
                    % dont attempt to delete if it is the first image
                    if(strcmp(app.prevImageFile, '')==0)
                        delete(fullfile(app.label_targetDir.Text, app.prevImageFile))
                    end
                    copyfile([app.imagePath app.imageFile], app.label_targetDir.Text);
                    % remember name this image in case user changes it; this to delete it.
                    app.prevImageFile = app.imageFile;
                    app.imageChangeFlag = 0;
                end
                
                %save file to disk and tell user app can be closed
                %TODO -- if directory gets deleted between its creation and save, this will crap out?
                %TODO -- does this work cross platform?
                %TODO -- save image internally dont make a dir
                save(fullfile(app.label_targetDir.Text, [app.editF_saveAs.Value '.mat']), 'stdNodeNames','stdName','stdImageName','-mat');
                msgbox({'All Saved!!!';'You can keep modfying or just close the window'},'Success');
            end% if(app.createdFlag == 0)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ui_createNodeStandard = uifigure;
            app.ui_createNodeStandard.Name = 'Create Node Standard';
            app.tabGroup = uitabgroup(app.ui_createNodeStandard);
            app.tab_start = uitab(app.tabGroup);
            app.tab_start.Title = 'Start';
            app.panel_start = uipanel(app.tab_start);
            app.label_targetDir = uilabel(app.panel_start);
            app.label_targetDir.Tooltip = {'No spaces'; ' no symbols'; ' no underscores no extensions.'; 'Just use camelCase. There is no error checking.'};
            app.label_targetDir.Text = '';
            app.button_create = uibutton(app.panel_start, 'push');
            app.button_create.ButtonPushedFcn = createCallbackFcn(app, @button_createPushed, true);
            app.button_create.Text = 'Create (2)';
            app.button_changeDir = uibutton(app.panel_start, 'push');
            app.button_changeDir.ButtonPushedFcn = createCallbackFcn(app, @button_changeDirPushed, true);
            app.button_changeDir.Text = 'Change Directory (1)';
            app.SaveAsEditFieldLabel = uilabel(app.panel_start);
            app.SaveAsEditFieldLabel.Text = 'Save As:';
            app.editF_saveAs = uieditfield(app.panel_start, 'text');
            app.editF_saveAs.Tooltip = {'No spaces no symbols no underscores no extensions.'; 'Just use camelCase. There is no error checking.'};
            app.tab_nodes = uitab(app.tabGroup);
            app.tab_nodes.Title = 'Node Creation and Definition';
            app.panel_nodes = uipanel(app.tab_nodes);
            app.table_recordedNodes = uitable(app.panel_nodes);
            app.table_recordedNodes.ColumnName = {'Nodes'; 'R'; 'L'; 'X'; 'Y'; 'Z'};
            app.table_recordedNodes.RowName = {};
            app.table_recordedNodes.ColumnEditable = true;
            app.table_recordedNodes.CellSelectionCallback = createCallbackFcn(app, @table_recordedNodesCellSelection, true);
            app.button_make = uibutton(app.panel_nodes, 'push');
            app.button_make.ButtonPushedFcn = createCallbackFcn(app, @button_makeButtonPushed, true);
            app.button_make.Text = 'Make(4)>>';
            app.button_deleteSelection = uibutton(app.panel_nodes, 'push');
            app.button_deleteSelection.ButtonPushedFcn = createCallbackFcn(app, @button_deleteSelectionPushed, true);
            app.button_deleteSelection.Text = 'Delete Selection (3)';
            app.button_uploadImage = uibutton(app.panel_nodes, 'push');
            app.button_uploadImage.ButtonPushedFcn = createCallbackFcn(app, @button_uploadImageButtonPushed, true);
            app.button_uploadImage.Text = 'Upload Image (5)';
            app.uia_image = uiaxes(app.panel_nodes);
            app.button_openImage = uibutton(app.panel_nodes, 'push');
            app.button_openImage.ButtonPushedFcn = createCallbackFcn(app, @button_openImageButtonPushed, true);
            app.button_openImage.Enable = 'off';
            app.button_openImage.Text = 'Open Image in Window';
            app.button_addRow = uibutton(app.panel_nodes, 'push');
            app.button_addRow.ButtonPushedFcn = createCallbackFcn(app, @button_addRowButtonPushed, true);
            app.button_addRow.Text = 'Add Row (3)';
            app.label_tableExplanation = uilabel(app.panel_nodes);
            app.label_tableExplanation.Text = 'Symetry R/L make independent nodes. X/Y/Z are node components.';
            app.NodeCoordinatesListLabel = uilabel(app.panel_nodes);
            app.NodeCoordinatesListLabel.Text = 'Node Coordinates List';
            app.textA_nodeList = uitextarea(app.panel_nodes);
            app.textA_nodeList.Editable = 'off';
            app.tab_nodeGraph = uitab(app.tabGroup);
            app.tab_nodeGraph.Title = 'Node Graph Mapping';
            app.panel_nodeGraphMap = uipanel(app.tab_nodeGraph);
            app.table_adjacencyMatrix = uitable(app.panel_nodeGraphMap);
            app.table_adjacencyMatrix.ColumnName = {''};
            app.table_adjacencyMatrix.RowName = {};
            app.table_adjacencyMatrix.Visible = 'off';
            app.button_makeAdjacencyMatrix = uibutton(app.panel_nodeGraphMap, 'push');
            app.button_makeAdjacencyMatrix.ButtonPushedFcn = createCallbackFcn(app, @button_makeAdjacencyMatrixButtonPushed, true);
            app.button_makeAdjacencyMatrix.Text = 'Make Adjacency Matrix (6)';
            app.ResizeColWidthsSpinnerLabel = uilabel(app.panel_nodeGraphMap);
            app.ResizeColWidthsSpinnerLabel.Text = 'Resize Col Widths';
            app.spinner_resizeColWidths = uispinner(app.panel_nodeGraphMap);
            app.spinner_resizeColWidths.Step = 5;
            app.spinner_resizeColWidths.Limits = [1 400];
            app.spinner_resizeColWidths.ValueDisplayFormat = '%.0f';
            app.spinner_resizeColWidths.ValueChangedFcn = createCallbackFcn(app, @spinner_resizeColWidthsValueChanged, true);
            app.spinner_resizeColWidths.Value = 30;
            app.button_makeGraph = uibutton(app.panel_nodeGraphMap, 'push');
            app.button_makeGraph.ButtonPushedFcn = createCallbackFcn(app, @button_makeGraphButtonPushed, true);
            app.button_makeGraph.Text = 'Make Graph (7)';
            app.button_openGraphvsImage = uibutton(app.panel_nodeGraphMap, 'push');
            app.button_openGraphvsImage.Text = 'Open Graph vs Image (8)';
            app.axes_nodeGraph = uiaxes(app.panel_nodeGraphMap);
            app.tab_nameEdges = uitab(app.tabGroup);
            app.tab_nameEdges.Title = 'Name Edges And Node Types';
            app.panel_nameEdges = uipanel(app.tab_nameEdges);
            app.table_edgeNames = uitable(app.panel_nameEdges);
            app.table_edgeNames.ColumnName = {'Node n'; 'Node k'; 'Edge Name'};
            app.table_edgeNames.RowName = {};
            app.table_edgeNames.ColumnEditable = [false false true];
            app.table_nodeTypes = uitable(app.panel_nameEdges);
            app.table_nodeTypes.ColumnName = {'Name'; 'Node/Joint'; 'Origin?'};
            app.table_nodeTypes.RowName = {};
            app.table_nodeTypes.ColumnEditable = [false true true];
            app.button_populateEdgeNodeTables = uibutton(app.panel_nameEdges, 'push');
            app.button_populateEdgeNodeTables.ButtonPushedFcn = createCallbackFcn(app, @button_populateEdgeNodeTablesButtonPushed, true);
            app.button_populateEdgeNodeTables.Text = 'Populate Tables (9)';
            app.button_doneFillEmptySpaces = uibutton(app.panel_nameEdges, 'push');
            app.button_doneFillEmptySpaces.ButtonPushedFcn = createCallbackFcn(app, @button_doneFillEmptySpacesPushed, true);
            app.button_doneFillEmptySpaces.Text = 'Done / Fill Empty Spaces (10)';
            app.tab_MakeBodyKinematics = uitab(app.tabGroup);
            app.tab_MakeBodyKinematics.Title = 'Make Body Kinematics';
            app.panel_makeBodyKinematics = uipanel(app.tab_MakeBodyKinematics);
            app.button_createBodyKinematics = uibutton(app.panel_makeBodyKinematics, 'push');
            app.button_createBodyKinematics.ButtonPushedFcn = createCallbackFcn(app, @button_createBodyKinematicsButtonPushed, true);
            app.button_createBodyKinematics.Text = 'Create Body Kinematics (11)';
            app.button_saveAndFinish = uibutton(app.panel_makeBodyKinematics, 'push');
            app.button_saveAndFinish.ButtonPushedFcn = createCallbackFcn(app, @button_saveAndFinishButtonPushed, true);
            app.button_saveAndFinish.Text = 'Save and Finish! (12)';
            app.uia_bodyGraph = uiaxes(app.panel_makeBodyKinematics);
            app.button_prev = uibutton(app.ui_createNodeStandard, 'push');
            app.button_prev.ButtonPushedFcn = createCallbackFcn(app, @button_prevButtonPushed, true);
            app.button_prev.Text = '<<Prev';
            app.button_next = uibutton(app.ui_createNodeStandard, 'push');
            app.button_next.ButtonPushedFcn = createCallbackFcn(app, @button_nextButtonPushed, true);
            app.button_next.Text = 'Next>>';
            app.Label_errorMsg = uilabel(app.ui_createNodeStandard);
            app.Label_errorMsg.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = createNodeStandard

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ui_createNodeStandard)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ui_createNodeStandard)
        end
    end
end