classdef mapMotionCsvToNodeStd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ui_template                  matlab.ui.Figure
        tab_group                    matlab.ui.container.TabGroup
        tab_loadData                 matlab.ui.container.Tab
        panel_loadData               matlab.ui.container.Panel
        button_loadFromFile          matlab.ui.control.Button
        label_loadFromFile           matlab.ui.control.Label
        button_csvSelect             matlab.ui.control.Button
        chkb_csvLock                 matlab.ui.control.CheckBox
        button_nodeStandard          matlab.ui.control.Button
        chkb_nodeStandardLock        matlab.ui.control.CheckBox
        button_createNodeStd         matlab.ui.control.Button
        label_studyDirectoryListBox  matlab.ui.control.Label
        list_studyDir                matlab.ui.control.ListBox
        label_csvFile                matlab.ui.control.Label
        text_csvDataFile             matlab.ui.control.EditField
        label_nodeStandard           matlab.ui.control.Label
        text_nodeStandardFile        matlab.ui.control.EditField
        button_browseWorkingDir      matlab.ui.control.Button
        label_workingDir             matlab.ui.control.Label
        tab_identifyNodes            matlab.ui.container.Tab
        panel_identifyNodes          matlab.ui.container.Panel
        tab_refineData               matlab.ui.container.Tab
        panel_refineData             matlab.ui.container.Panel
        tan_saveAll                  matlab.ui.container.Tab
        panel_saveAll                matlab.ui.container.Panel
        button_prev                  matlab.ui.control.Button
        button_next                  matlab.ui.control.Button
        label_errorMsg               matlab.ui.control.Label
    end


%---------------------------- CONSTANT PROPERTIES ---------------------------------%    
    
    properties (Access = private, Constant = true)
        % variables that control app's visibility workflow function
        isPanelChar = 'panel_';
        initPanelChar = 'panel_loadData';
        
        % number of initial steps, pair with startedFlag non constant property
        % change this according to the app's workflow before it unlocks the buton next
        numInitialSteps = 3;
    end
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'mapMotionCsvToNodeStd';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
    end
    

%----------------------------- CLASS PROPERTIES ----------------------------------%
    
     properties (Access = private)
        % started flag to make sure there is name and directory
        startedFlag = 0;
        
        % tab control variable, start at first tab
        tc = 1;

        % this variable stores the working directory
        workingDir = '';
        
        % the file selected from the directory list
        listSelectedFile = ''
        
    end
    
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%
    
    methods (Access = private)
        
        function loadAppState(app)
            %TODO -- error checking here try catch?
            %TODO -- supress warnings when loading file for fig?
            [tfile, tpath] = uigetfile('*.mat');
            loadedFile = load(fullfile(tpath, tfile));
            
            if(~strcmp(class(loadedFile.app), app.appName))
                app.label_errorMsg = 'ERROR: not the right kind of file';
            
            else    
                % if load previous datafile, then populate all variables
                % app-defined user properties
                
                
            end%if file is correct
            
            % delete loaded instance, but first display the name of the standard selected
            app.label_loadFromFile.Text = fullfile(tpath, tfile);
            clear('loadedFile', 'tfile', 'tpath');
            
        end%load file function
        
    
        function loadWorkingDirFiles(app)   
                %TODO -- should trim this to show a ../../dir
                app.label_workingDir.Text = app.workingDir;

                % dont display . and ..
                tmp = cellstr(ls(app.workingDir));
                app.list_studyDir.Items = tmp(3:end);
                
                % enable buttons that add files now, disable browsing
                app.button_browseWorkingDir.Enable = 'off';
                app.button_loadFromFile.Enable     = 'off';
                app.button_csvSelect.Enable     = 'on';
                app.button_nodeStandard.Enable  = 'on';
                
                % increment the starting flag increment x/numInitialSteps
                %TODO -- does this belong on a small function?
                incStartFlag(app, 1);
                
        end%load working dir function
        
        function v = incStartFlag(app, cnt)
            % increase or decrease the start flag with bound checking
            % this ommits the value of cnt and only uses its sign.
            if(cnt>0 && app.startedFlag<app.numInitialSteps)
                app.startedFlag = app.startedFlag + 1;
            elseif(cnt<0 && app.startedFlag>0)
                app.startedFlag = app.startedFlag - 1;
            end
            
            % determine if the start is done.
            if(app.startedFlag>=app.numInitialSteps)
                v = true;
            else
                v = false;
            end
        end%increase start flag
    
    end%private methods


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tworkingDir)

            % fist turn all tabs off
            % get names of all panels and indexes in app. computing here is slower but app may change?
            tnames  = fieldnames(app);
            tpanels = find(contains(tnames,app.isPanelChar));
            
            for(i=1: length(tpanels))
                app.(tnames{tpanels(i)}).Visible = 'off';
            end
            
            % the first pannel and tab controlled by class constants
            tpanel = find(contains(tnames,app.initPanelChar));
            app.(tnames{tpanel}).Visible = 'on';
            
            % activate appropiate tab. at init = 1
            app.tab_group.SelectedTab = app.tab_group.Children(app.tc);
            
            % if an input is sent to the app then assign working directory
            %TODO -- ommiting try catch for now because this comes within the app
            if(nargin==2)
                app.workingDir = tworkingDir;
                % load files into directory list if user provides this input
                loadWorkingDirFiles(app);
            end
            
        end

        % Button pushed function: button_next
        function button_nextButtonPushed(app, event)
            % make sure user cant proceed unless name and directory are changed.
            if(~incStartFlag(app, 0))
                app.label_errorMsg.Text = 'ERROR: This section must be completed.';
            else
                % remove error labels if any
                app.label_errorMsg.Text = '';
                
                % fist turn all tabs off
                % get names of all panels and indexes in app. computing here is slower but app may change?
                tnames  = fieldnames(app);
                tpanels = find(contains(tnames,app.isPanelChar));
                
                for(i=1: length(tpanels))
                    app.(tnames{tpanels(i)}).Visible = 'off';
                end
                
                % increment tab control if no bigger than size of tab
                if(app.tc < size(app.tab_group.Children,1))
                    app.tc = app.tc+1;
                end
                
                app.(tnames{tpanels(app.tc)}).Visible = 'on';
                
                % activate appropiate tab
                app.tab_group.SelectedTab = app.tab_group.Children(app.tc);
            end
        end

        % Button pushed function: button_prev
        function button_prevButtonPushed(app, event)
            % fist turn all tabs off
            % get names of all panels and indexes in app. computing here is slower but app may change?
            tnames  = fieldnames(app);
            tpanels = find(contains(tnames,app.isPanelChar));
            
            for(i=1: length(tpanels))
                app.(tnames{tpanels(i)}).Visible = 'off';
            end
            
            % change tab control if no smaller than one
            if(app.tc > 1)
                app.tc = app.tc-1;
            end
            
            app.(tnames{tpanels(app.tc)}).Visible = 'on';
            
            % activate appropiate tab
            app.tab_group.SelectedTab = app.tab_group.Children(app.tc);
        end

        % Button pushed function: button_loadFromFile
        function button_loadFromFileButtonPushed(app, event)
            % load from an existing file
            loadAppState(app);
            app.button_loadFromFile.Enable = 'off';
        end

        % Button pushed function: button_browseWorkingDir
        function button_browseWorkingDirButtonPushed(app, event)
            % select a directory for this study
            try
                %TODO -- for some reason this sometimes crashes if it is on my first screen only??
                app.workingDir = uigetdir('Select the study directory  ');
                
            catch
                %TODO -- popup mesages could be hidden in the back?   
                msgbox({'Not a valid entry';'You must select a directory'},'Invalid','warn');
                app.text_workingDir.Value = "Select a study directory";
            end
            
            % load files into directory list
            loadWorkingDirFiles(app);
            
        end

        % Value changed function: chkb_csvLock
        function chkb_csvLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkb_csvLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.button_csvSelect.Enable = 'off';
                incStartFlag(app, 1);
            else
                app.button_csvSelect.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Button pushed function: button_csvSelect
        function button_csvSelectButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that ta csv is actually selected
            app.text_csvDataFile.Value = app.listSelectedFile;
            
            %TODO -- check that this file is a csv File
            % lock the value trough the checkbox
            app.chkb_csvLock.Value = 1;
            chkb_csvLockValueChanged(app, event); 
        end

        % Value changed function: list_studyDir
        function list_studyDirValueChanged(app, event)
            % updates the value of the user selecton in a private variable to be used
            % as a pointer to associate it with a type of file in button_xxxSelectPushed
            value = app.list_studyDir.Value;
            app.listSelectedFile = string(value);
        end

        % Value changed function: chkb_nodeStandardLock
        function chkb_nodeStandardLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkb_nodeStandardLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.button_nodeStandard.Enable = 'off';
                app.button_createNodeStd.Enable = 'off';
                incStartFlag(app, 1);
            else
                app.button_nodeStandard.Enable = 'on';
                app.button_createNodeStd.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Button pushed function: button_nodeStandard
        function button_nodeStandardButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that node standard is actually selected
            app.text_nodeStandardFile.Value = app.listSelectedFile;
            
            %TODO -- check that this file is a csv File
            % lock the value trough the checkbox
            app.chkb_nodeStandardLock.Value = 1;
            chkb_nodeStandardLockValueChanged(app, event); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ui_template = uifigure;
            app.ui_template.Name = 'UI Figure';
            app.tab_group = uitabgroup(app.ui_template);
            app.tab_loadData = uitab(app.tab_group);
            app.tab_loadData.Title = 'Load Data ';
            app.panel_loadData = uipanel(app.tab_loadData);
            app.button_loadFromFile = uibutton(app.panel_loadData, 'push');
            app.button_loadFromFile.ButtonPushedFcn = createCallbackFcn(app, @button_loadFromFileButtonPushed, true);
            app.button_loadFromFile.Text = 'Load From File (0)';
            app.label_loadFromFile = uilabel(app.panel_loadData);
            app.label_loadFromFile.Text = '';
            app.button_csvSelect = uibutton(app.panel_loadData, 'push');
            app.button_csvSelect.ButtonPushedFcn = createCallbackFcn(app, @button_csvSelectButtonPushed, true);
            app.button_csvSelect.Enable = 'off';
            app.button_csvSelect.Text = '<<';
            app.chkb_csvLock = uicheckbox(app.panel_loadData);
            app.chkb_csvLock.ValueChangedFcn = createCallbackFcn(app, @chkb_csvLockValueChanged, true);
            app.chkb_csvLock.Text = 'Lock';
            app.button_nodeStandard = uibutton(app.panel_loadData, 'push');
            app.button_nodeStandard.ButtonPushedFcn = createCallbackFcn(app, @button_nodeStandardButtonPushed, true);
            app.button_nodeStandard.Enable = 'off';
            app.button_nodeStandard.Text = '<<';
            app.chkb_nodeStandardLock = uicheckbox(app.panel_loadData);
            app.chkb_nodeStandardLock.ValueChangedFcn = createCallbackFcn(app, @chkb_nodeStandardLockValueChanged, true);
            app.chkb_nodeStandardLock.Text = 'Lock';
            app.button_createNodeStd = uibutton(app.panel_loadData, 'push');
            app.button_createNodeStd.Text = 'Create Node Standard';
            app.label_studyDirectoryListBox = uilabel(app.panel_loadData);
            app.label_studyDirectoryListBox.Text = 'Study Directory';
            app.list_studyDir = uilistbox(app.panel_loadData);
            app.list_studyDir.Items = {};
            app.list_studyDir.ValueChangedFcn = createCallbackFcn(app, @list_studyDirValueChanged, true);
            app.list_studyDir.Value = {};
            app.label_csvFile = uilabel(app.panel_loadData);
            app.label_csvFile.Text = 'CSV File';
            app.text_csvDataFile = uieditfield(app.panel_loadData, 'text');
            app.text_csvDataFile.Editable = 'off';
            app.label_nodeStandard = uilabel(app.panel_loadData);
            app.label_nodeStandard.Text = 'Node Standard';
            app.text_nodeStandardFile = uieditfield(app.panel_loadData, 'text');
            app.text_nodeStandardFile.Editable = 'off';
            app.button_browseWorkingDir = uibutton(app.panel_loadData, 'push');
            app.button_browseWorkingDir.ButtonPushedFcn = createCallbackFcn(app, @button_browseWorkingDirButtonPushed, true);
            app.button_browseWorkingDir.Text = 'Browse Working Dir';
            app.label_workingDir = uilabel(app.panel_loadData);
            app.label_workingDir.Text = '';
            app.tab_identifyNodes = uitab(app.tab_group);
            app.tab_identifyNodes.Title = 'Identify Nodes';
            app.panel_identifyNodes = uipanel(app.tab_identifyNodes);
            app.tab_refineData = uitab(app.tab_group);
            app.tab_refineData.Title = 'Refine Data';
            app.panel_refineData = uipanel(app.tab_refineData);
            app.tan_saveAll = uitab(app.tab_group);
            app.tan_saveAll.Title = 'Save All';
            app.panel_saveAll = uipanel(app.tan_saveAll);
            app.button_prev = uibutton(app.ui_template, 'push');
            app.button_prev.ButtonPushedFcn = createCallbackFcn(app, @button_prevButtonPushed, true);
            app.button_prev.Text = '<<Prev';
            app.button_next = uibutton(app.ui_template, 'push');
            app.button_next.ButtonPushedFcn = createCallbackFcn(app, @button_nextButtonPushed, true);
            app.button_next.Text = 'Next>>';
            app.label_errorMsg = uilabel(app.ui_template);
            app.label_errorMsg.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mapMotionCsvToNodeStd(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ui_template)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ui_template)
        end
    end
end