classdef biolocomotionGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ui_biolocomotionGui        matlab.ui.Figure
        label_title                matlab.ui.control.Label
        button_createNodeStandard  matlab.ui.control.Button
        button_mapMotionData       matlab.ui.control.Button
        button_createCameraObj     matlab.ui.control.Button
        button_animationViewer     matlab.ui.control.Button
        uia_appImage               matlab.ui.control.UIAxes
        button_selectWorkingDir    matlab.ui.control.Button
        label_errorMsg             matlab.ui.control.Label
        label_workingDir           matlab.ui.control.Label
        button_createBlankStudy    matlab.ui.control.Button
        label_divider              matlab.ui.control.Label
        button_advancedDataAdj     matlab.ui.control.Button
        button_egg                 matlab.ui.control.Button
    end

    
%----------------------------- CONSTANT PROPERTIES ---------------------------------%    
    properties (Access = private, Constant = true)
        %TODO -- how should this look once the app gets compiled?
        rootDir = 'C:\Users\alberto-bortoni\OneDrive\research\biolocomotion\';
    end    

    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'biolocomotionGui';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
    end

%------------------------------- CLASS PROPERTIES ----------------------------------%
     properties (Access = private)
        % this variable stores the working directory
        workingDir = '';
     end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%     
     methods (Access = private)   
        
        function setInitialGuiParam(app)
            %TODO -- distinguish between Root directory, that is the app, and working directory, the study one
            %TODO -- relative path here
            imshow(fullfile(app.rootDir, 'res\images\bat-logo.png'), 'Parent', app.uia_appImage);
            app.uia_appImage.Visible = 'on';
        end%setInitialGuiParam
%-----------------------------------------------------------------------------------%
         
         function dispMsg(app, ttype, tmsg)
            % For log messaging blue
            if(strcmpi(ttype, 'L'))
                app.label_errorMsg.Text = ['LOG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.45,0.74];
                
            % for error messages red
            elseif(strcmpi(ttype, 'E'))
                app.label_errorMsg.Text = ['ERROR: ' tmsg];
                app.label_errorMsg.FontColor = [0.64,0.08,0.18];
                
            % general propose message black
            elseif(strcmpi(ttype, 'M'))
                app.label_errorMsg.Text = ['MSG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.00,0.00];
            
            % for clearing the mesage
            elseif(strcmpi(ttype, 'C'))
                app.label_errorMsg.Text = '';
            end
        end%dispMsg
%-----------------------------------------------------------------------------------%
     
    end%private methods   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setInitialGuiParam(app);
        end

        % Button pushed function: button_selectWorkingDir
        function button_selectWorkingDirButtonPushed(app, event)
            %TODO -- make default init directory the home directory of the user
            try
                % allow the user to navigate to the study directory
                app.workingDir = uigetdir();
                app.label_workingDir.Text = app.workingDir;
                % clear errors if any
                dispMsg(app, 'c');
                % permit user to select all other buttons
                app.button_createNodeStandard.Enable = 'on';
                app.button_mapMotionData.Enable      = 'on';
                app.button_createCameraObj.Enable    = 'on';
                app.button_animationViewer.Enable    = 'on';
            catch
                dispMsg(app, 'e', 'something went wrong with the directory selection');
            end
        end

        % Button pushed function: button_createNodeStandard
        function button_createNodeStandardButtonPushed(app, event)
            createNodeStandardGui(app.workingDir);
        end

        % Button pushed function: button_mapMotionData
        function button_mapMotionDataButtonPushed(app, event)
            motionCsvToNodeStdMapGui(app.workingDir);
        end

        % Button pushed function: button_createCameraObj
        function button_createCameraObjButtonPushed(app, event)
            createCameraObjGui(app.workingDir);
        end

        % Button pushed function: button_animationViewer
        function button_animationViewerButtonPushed(app, event)
            motionAnimationViewerGui(app.workingDir);
        end

        % Button pushed function: button_createBlankStudy
        function button_createBlankStudyButtonPushed(app, event)
            createBlankStudyArchitectureGui();
        end

        % Button pushed function: button_advancedDataAdj
        function button_advancedDataAdjButtonPushed(app, event)
            %TODO -- app that calls curve fitting toolbox and filter, etc
        end

        % Button pushed function: button_egg
        function button_eggButtonPushed(app, event)
            tegg    = imread(fullfile(app.rootDir, 'res\images\egg.jpg'));
            teggbig = imresize(tegg, 4);
            imshow(teggbig);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ui_biolocomotionGui = uifigure('Visible', 'off');
            app.ui_biolocomotionGui.Name = 'Biolocomotion Toolbox';
            app.label_title = uilabel(app.ui_biolocomotionGui);
            app.label_title.Text = 'BIOLOCOMOTION TOOLBOX';
            app.button_createNodeStandard = uibutton(app.ui_biolocomotionGui, 'push');
            app.button_createNodeStandard.ButtonPushedFcn = createCallbackFcn(app, @button_createNodeStandardButtonPushed, true);
            app.button_createNodeStandard.Enable = 'off';
            app.button_createNodeStandard.Text = 'Create A Node Standard';
            app.button_mapMotionData = uibutton(app.ui_biolocomotionGui, 'push');
            app.button_mapMotionData.ButtonPushedFcn = createCallbackFcn(app, @button_mapMotionDataButtonPushed, true);
            app.button_mapMotionData.Enable = 'off';
            app.button_mapMotionData.Text = 'Map Motion Data to Node Standard';
            app.button_createCameraObj = uibutton(app.ui_biolocomotionGui, 'push');
            app.button_createCameraObj.ButtonPushedFcn = createCallbackFcn(app, @button_createCameraObjButtonPushed, true);
            app.button_createCameraObj.Enable = 'off';
            app.button_createCameraObj.Text = 'Create Camera Objects';
            app.button_animationViewer = uibutton(app.ui_biolocomotionGui, 'push');
            app.button_animationViewer.ButtonPushedFcn = createCallbackFcn(app, @button_animationViewerButtonPushed, true);
            app.button_animationViewer.Enable = 'off';
            app.button_animationViewer.Text = 'Animation Viewer';
            app.uia_appImage = uiaxes(app.ui_biolocomotionGui);
            app.uia_appImage.Visible = 'off';
            app.button_selectWorkingDir = uibutton(app.ui_biolocomotionGui, 'push');
            app.button_selectWorkingDir.ButtonPushedFcn = createCallbackFcn(app, @button_selectWorkingDirButtonPushed, true);
            app.button_selectWorkingDir.Text = {'Select A Study'; 'Working Directory'};
            app.label_errorMsg = uilabel(app.ui_biolocomotionGui);
            app.label_errorMsg.Text = '';
            app.label_workingDir = uilabel(app.ui_biolocomotionGui);
            app.label_workingDir.Text = '';
            app.button_createBlankStudy = uibutton(app.ui_biolocomotionGui, 'push');
            app.button_createBlankStudy.ButtonPushedFcn = createCallbackFcn(app, @button_createBlankStudyButtonPushed, true);
            app.button_createBlankStudy.Text = 'Create a Blank Study';
            app.label_divider = uilabel(app.ui_biolocomotionGui);
            app.label_divider.Text = '------------------------------------';
            app.button_advancedDataAdj = uibutton(app.ui_biolocomotionGui, 'push');
            app.button_advancedDataAdj.ButtonPushedFcn = createCallbackFcn(app, @button_advancedDataAdjButtonPushed, true);
            app.button_advancedDataAdj.Enable = 'off';
            app.button_advancedDataAdj.Text = 'Advanced Data Adjusting';
            app.button_egg = uibutton(app.ui_biolocomotionGui, 'push');
            app.button_egg.ButtonPushedFcn = createCallbackFcn(app, @button_eggButtonPushed, true);
            app.button_egg.Text = '';
            % Show the figure after all components are created
            app.ui_biolocomotionGui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = biolocomotionGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ui_biolocomotionGui)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ui_biolocomotionGui)
        end
    end
end