classdef computeNullStateMechGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ui_computeNullStateMech   matlab.ui.Figure
        tabg_group                matlab.ui.container.TabGroup
        tab_loadData              matlab.ui.container.Tab
        panel_loadData            matlab.ui.container.Panel
        label_loadFromFile        matlab.ui.control.Label
        button_motionDataSelect   matlab.ui.control.Button
        chkB_motionDataLock       matlab.ui.control.CheckBox
        label_filesAvail          matlab.ui.control.Label
        listB_filesAvail          matlab.ui.control.ListBox
        label_motionDataFile      matlab.ui.control.Label
        editF_motionDataFile      matlab.ui.control.EditField
        label_workingDir          matlab.ui.control.Label
        label_step1               matlab.ui.control.Label
        button_browseToMapFile    matlab.ui.control.Button
        label_divider             matlab.ui.control.Label
        label_existingFile        matlab.ui.control.Label
        button_refreshWorkingDir  matlab.ui.control.Button
        chkB_lockTrial            matlab.ui.control.CheckBox
        label_step0               matlab.ui.control.Label
        label_trialsAvail         matlab.ui.control.Label
        listB_trialsAvail         matlab.ui.control.ListBox
        button_mapTemplate        matlab.ui.control.Button
        chkB_mapTemplate          matlab.ui.control.CheckBox
        label_mapTemplate         matlab.ui.control.Label
        editF_mapTemplate         matlab.ui.control.EditField
        tab_worldToBody           matlab.ui.container.Tab
        panel_worldToBody         matlab.ui.container.Panel
        button_getOriginData      matlab.ui.control.Button
        tab_trimData              matlab.ui.container.Tab
        panel_trimData            matlab.ui.container.Panel
        tab_refineData            matlab.ui.container.Tab
        panel_refineData          matlab.ui.container.Panel
        tab_saveAll               matlab.ui.container.Tab
        panel_saveAll             matlab.ui.container.Panel
        button_saveAllAndExit     matlab.ui.control.Button
        label_savedAs             matlab.ui.control.Label
        button_saveTemplate       matlab.ui.control.Button
        label_saveTemplateName    matlab.ui.control.Label
        button_copyToWorkspace    matlab.ui.control.Button
        label_copyToWorkspace     matlab.ui.control.Label
        editF_copyToWorkspace     matlab.ui.control.EditField
        button_prev               matlab.ui.control.Button
        button_next               matlab.ui.control.Button
        label_errorMsg            matlab.ui.control.Label
    end


%---------------------------- CONSTANT PROPERTIES ---------------------------------%    
    
    properties (Access = private, Constant = true)
        % variables that control app's visibility workflow function
        isTabChar = 'tab_';
        
        % number of initial steps, pair with startedFlag non constant property
        % change this according to the app's workflow before it unlocks the buton next
        numInitialSteps = 1;
    end
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'computeNullStateMechGui';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
        
        % important/ relevant output from this app
        savedVarName = 'computeNullStateMech';
        
        % the full app, required to populate this app with a previous session
        % this contains ALL components, even those stored in savedVarName
        savedAppName = 'computeNullStateMechApp';
        savedAppTemplateName = 'computeNullStateMechTemplateApp';
        
        % relevant variables to save in savedVarName
        % savedAppName saves this plus everything else too
        %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
        varsToSaveinStd =  {'appVersion'};

    end
    

%---------------------------- VARIABLE PROPERTIES --------------------------------%
    
    properties (Access = private)
        % this variable stores the working directory
        workingDir = '';
        trialDir   = '';
        
        % started flag to make sure there is name and directory
        startedFlag = 0;
        
        % tab control variable, start at first tab
        tabControl = 1;
        
        % application tabs, used to move around with buttons, computed at startup
        applicationTabs;
        
        % files to use in directory
        filesAvailiable;
        filesAvailiableDisplay;
    end
         
    properties (Access = public)
        % where all csv data is saved
        nodeStd;
        motionData;
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%
    
    methods (Access = private)
        
        function setInitialGuiParam(app)
            % fist turn all panels off
            % get names of all tabs and indexes in app.
            tnames  = fieldnames(app);
            
            % tabs are in the order that they apear in the gui in sequence
            %TODO -- if tabs have multiple children this wont work
            app.applicationTabs = tnames(contains(tnames,app.isTabChar));
            
            % disable all panels
            for(i=1: length(app.applicationTabs))
                app.(app.applicationTabs{i}).Children.Visible = 'off';
            end
            
            % the first pannel and tab controlled by class tab control
            app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
            app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            
            % load the study directory and display files
            loadWorkingDirFiles(app);
        end%setInitialGuiParam
%-----------------------------------------------------------------------------------%
        
        function loadWorkingDirFiles(app)   
                %TODO -- should trim this to show a ../../dir
                app.label_workingDir.Text = app.workingDir;

                % dont display the trials availiable
                tignorefiles = {'.'; '..'; '.git'};
                tmp = cellstr(ls(fullfile(app.workingDir, 'trial')));
                tmp = setdiff(tmp,tignorefiles);
                app.listB_trialsAvail.Items = tmp;
                
                % make sure a trial is selected
                if(~isempty(app.listB_trialsAvail.Value))
                    % dont display the useless ones
                    tres        = displayDirRecursively(fullfile(app.workingDir, '\res'));
                    tres        = fullfile('\res',tres);
                    tshare      = displayDirRecursively(fullfile(app.workingDir, '\share'));
                    tshare      = fullfile('\share',tshare);
                    ttrialfiles = displayDirRecursively(fullfile(app.workingDir, '\trial', app.listB_trialsAvail.Value));
                    ttrialfiles = fullfile('\trial',app.listB_trialsAvail.Value,ttrialfiles);
                    
                    % append and remove empty ones
                    tmp = [tres; tshare; ttrialfiles];
                    app.filesAvailiable = tmp(~strcmp(tmp, ''));
                    app.filesAvailiableDisplay = compressPathNmes(app.filesAvailiable);
                    app.listB_filesAvail.Items = app.filesAvailiableDisplay;
                end
        end%loadWorkingDirFiles
%-----------------------------------------------------------------------------------%

        function findAppInstance(app)
            % see if there is an instance of this app in the working directory
            if(~(isfile(fullfile(app.trialDir, [app.savedAppName '.mat']))))
                app.label_existingFile.Text = {['There is no ' app.savedAppName ' File in the Working Directory.'] 'Saving will create a new one.'};
                loadEmptyAppState(app);
            else
                % try to open the file
                try
                    % disable wargings from saving stuff such as uia components
                    warning('off');
                    tmatfile = load(fullfile(app.trialDir, [app.savedAppName '.mat']));
                    loadedFile = tmatfile.(app.savedAppName);
                    
                    % turn warnings back on
                    warning('on');
                    %TODO -- this is a matlab bug
                    warning off MATLAB:subscripting:noSubscriptsSpecified;
                    
                % send a message and ask the user to take action 
                catch
                    msgbox({'There was an error loading the file, app wil close'; ...
                            'If probem persist you need to delete the saved mat file'}, 'Error','error');
                    delete(app);
                end
                % load variables to populate the instance of this app
                app.label_existingFile.Text = {['There is a ' app.savedAppName ' File in the Working Directory.'] 'Saving will overwrite it.'};
                loadAppState(app, loadedFile);
            end
        end%findAppInstance
%-----------------------------------------------------------------------------------%

        function loadEmptyAppState(app)
            % variables on tab one
            
        end%loadAppState
%-----------------------------------------------------------------------------------%

        function loadAppState(app, loadedFile)
            % variables on tab one
            
        end%loadAppState
%-----------------------------------------------------------------------------------%

        function incStartFlag(app, cnt)
            % increase or decrease the start flag with bound checking
            % this ommits the value of cnt and only uses its sign.
            if(cnt>0 && app.startedFlag<app.numInitialSteps)
                app.startedFlag = app.startedFlag + 1;
            elseif(cnt<0 && app.startedFlag>0)
                app.startedFlag = app.startedFlag - 1;
            end
            
            % determine if the start is done and enable prev and next buttons
            if(app.startedFlag>=app.numInitialSteps)
                app.button_next.Enable = 'on';
                app.button_prev.Enable = 'on';
            else
                app.button_next.Enable = 'off';
                app.button_prev.Enable = 'off';
            end
        end%incStartFlag
%-----------------------------------------------------------------------------------%
        
        function advanceTab(app, cnt)
            % first check if cnt will request a tab out of range
            if((app.tabControl+cnt)>length(app.applicationTabs) || (app.tabControl+cnt)<1)
                dispMsg(app, 'e', 'tab change request out of range');
            else
                % clear errors if any
                dispMsg(app, 'c');
                
                % disable the current panel
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'off';
                
                % increase tab control and activate next panel and tab
                app.tabControl = app.tabControl+cnt;
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
                app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            end
        end%advanceTab
%-----------------------------------------------------------------------------------%

        function s = varsToSaveinStdFcn(app)
            % saves just the relevant variables that come out of this class not to save ui elements
            for(i = 1:length(app.varsToSaveinStd))
                s.(app.varsToSaveinStd{i}) = app.(app.varsToSaveinStd{i});
            end                
        end%varsToSaveinStdFcn
%-----------------------------------------------------------------------------------%   
         
        function readMotionData(app)
            
        end%readNodeStandard
%-----------------------------------------------------------------------------------%

        function loadVarsFromTemplate(app, ttemplate)

        end%loadVarsFromTemplate
%-----------------------------------------------------------------------------------%

        function dispMsg(app, ttype, tmsg)
            % For log messaging blue
            if(strcmpi(ttype, 'L'))
                app.label_errorMsg.Text = ['LOG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.45,0.74];
                
            % for error messages red
            elseif(strcmpi(ttype, 'E'))
                app.label_errorMsg.Text = ['ERROR: ' tmsg];
                app.label_errorMsg.FontColor = [0.64,0.08,0.18];
                
            % general propose message black
            elseif(strcmpi(ttype, 'M'))
                app.label_errorMsg.Text = ['MSG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.00,0.00];
            
            % for clearing the mesage
            elseif(strcmpi(ttype, 'C'))
                app.label_errorMsg.Text = '';
            end
        end%dispMsg
%-----------------------------------------------------------------------------------%

    end%private methods

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tworkingDir)
            app.workingDir = tworkingDir;
            setInitialGuiParam(app);
        end

        % Button pushed function: button_next
        function button_nextButtonPushed(app, event)
            % move to next tab
            advanceTab(app, 1);
        end

        % Button pushed function: button_prev
        function button_prevButtonPushed(app, event)
            % move to previous tab
            advanceTab(app, -1);
        end

        % Value changed function: chkB_motionDataLock
        function chkB_motionDataLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_motionDataLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.button_motionDataSelect.Enable = 'off';
                incStartFlag(app, 1);
            else
                app.button_motionDataSelect.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Button pushed function: button_motionDataSelect
        function button_motionDataSelectButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that ta csv is actually selected
            % make sure there is a selection
            if(~isempty(app.listB_filesAvail.Value))
                % get the selected files in its extended name form
                tmp = app.listB_filesAvail.Value;
                tindex = arrayfun(@(k) find(strcmp(tmp,app.filesAvailiableDisplay)), 1:length(tmp));
                app.editF_motionDataFile.Value = app.filesAvailiable{tindex};
                
                % lock the value trough the checkbox
                app.chkB_motionDataLock.Value = 1;
                chkB_motionDataLockValueChanged(app);
            
                % clear errors if any
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'Select a file first');
            end 
        end

        % Button pushed function: button_saveAllAndExit
        function button_saveAllAndExitButtonPushed(app, event)
            % disable wargings from saving stuff such as uia components
            %TODO -- just dont try to save them to prevent warning
            warning('off');
            
            % make a struct of what I want to save to that they keep those variable names
            tmp.(app.savedAppName) = app;
            tmp.(app.savedVarName) = varsToSaveinStdFcn(app);
            
            % save file
            %TODO -- does this needs a try catch?
            save(fullfile(app.trialDir, [app.savedAppName '.mat']), '-struct', 'tmp');
            app.label_savedAs.Text = fullfile(app.trialDir, [app.savedAppName '.mat']);
            app.label_existingFile.Text = {['There is a ' app.savedAppName ' File in the Working Directory.'] 'Saving will overwrite it.'};
            loadWorkingDirFiles(app);
            
            % alert the user it is done
            msgbox({'All Saved!!!';'You can keep modfying or just close the window'},'Success');
            
            % turn warnings back on
            warning('on');
            %TODO -- this is a matlab bug
            warning off MATLAB:subscripting:noSubscriptsSpecified;
        end

        % Value changed function: chkB_mapTemplate
        function chkB_mapTemplateValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_mapTemplate.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            if(value == 1)
                app.button_mapTemplate.Enable = 'off';
            else
                app.button_mapTemplate.Enable = 'on';
            end
        end

        % Value changed function: listB_trialsAvail
        function listB_trialsAvailValueChanged(app, event)
            value = app.listB_trialsAvail.Value;
            loadWorkingDirFiles(app);
        end

        % Value changed function: chkB_lockTrial
        function chkB_lockTrialValueChanged(app, event)
            value = app.chkB_lockTrial.Value;
            % if the box is checked then update the trial data
            if(value == 1)
                % get the directory path of this trial, check for previous instance
                app.trialDir = fullfile(app.workingDir,'trial', app.listB_trialsAvail.Value);
                findAppInstance(app);
                
                % enable the selection of trials in list box
                app.listB_trialsAvail.Enable  = 'off';
                
                % then enable all interactivity
                app.editF_motionDataFile.Enable = 'on';
                app.editF_mapTemplate.Enable    = 'on';
                
                app.button_motionDataSelect.Enable = 'on';
                app.button_mapTemplate.Enable      = 'on';
                app.button_browseToMapFile.Enable  = 'on';
                
                app.chkB_motionDataLock.Enable = 'on';
                app.chkB_mapTemplate.Enable    = 'on';
            else
                % disable the selection of trials in list box
                app.listB_trialsAvail.Enable  = 'on';
                
                % clear existing file text
                app.label_existingFile.Text = '';
                
                % then enable all interactivity
                app.editF_motionDataFile.Enable   = 'off';
                app.editF_mapTemplate.Enable      = 'off';
                
                app.button_motionDataSelect.Enable = 'off';
                app.button_mapTemplate.Enable      = 'off';
                app.button_browseToMapFile.Enable  = 'off';
                
                app.chkB_motionDataLock.Enable = 'off';
                app.chkB_mapTemplate.Enable    = 'off';
                
                % clear all mapped information
                app.editF_motionDataFile.Value = '';
                app.editF_mapTemplate.Value    = '';
                
                app.chkB_motionDataLock.Value = 0;
                app.chkB_mapTemplate.Value    = 0;
                
                % clear all progres to move forward
                app.startedFlag = 0;
            end
        end

        % Button pushed function: button_saveTemplate
        function button_saveTemplateButtonPushed(app, event)
            % disable wargings from saving stuff such as uia components
            %TODO -- just dont try to save them to prevent warning
            warning('off');
            
            % make a struct of what I want to save to that they keep those variable names
            tmp.(app.savedAppName) = app;
            tmp.(app.savedVarName) = varsToSaveinStdFcn(app);
            
            % save file
            %TODO -- does this needs a try catch?
            save(fullfile(app.workingDir, 'share', [app.savedAppTemplateName '.mat']), '-struct', 'tmp');
            app.label_saveTemplateName.Text = fullfile(app.workingDir, 'share', [app.savedAppTemplateName '.mat']);
            loadWorkingDirFiles(app);
            
            % turn warnings back on
            warning('on');
            %TODO -- this is a matlab bug
            warning off MATLAB:subscripting:noSubscriptsSpecified;
        end

        % Button pushed function: button_copyToWorkspace
        function button_copyToWorkspaceButtonPushed(app, event)
            % first make sure that the name is valid
            if(isvarname(app.editF_copyToWorkspace.Value))
                % create a struct of the saved variables
                tmp.(app.savedVarName) = varsToSaveinStdFcn(app);
                
                % assign value to base workspace in matlab
                assignin('base',app.editF_copyToWorkspace.Value,tmp.(app.savedVarName));
                
                % clear error if any
                dispMsg(app, 'l', 'Copied to base workspace');  
            else
                dispMsg(app, 'e', 'The variable in field must be a valid matlab name');
            end
        end

        % Button pushed function: button_mapTemplate
        function button_mapTemplateButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that node standard is actually selected
            % make sure there is a selection
            if(~isempty(app.listB_filesAvail.Value))
                % get the selected files in its extended name form
                tmp = app.listB_filesAvail.Value;
                tindex = arrayfun(@(k) find(strcmp(tmp,app.filesAvailiableDisplay)), 1:length(tmp));
                app.editF_mapTemplate.Value = app.filesAvailiable{tindex};
                
                % lock the value trough the checkbox
                app.chkB_mapTemplate.Value = 1;
                chkB_mapTemplateValueChanged(app); 
                
                % clear errors if any
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'Select a file first');
            end
        end

        % Button pushed function: button_browseToMapFile
        function button_browseToMapFileButtonPushed(app, event)
            % make sure the field is not empty
            if(~isempty(app.editF_mapTemplate))
                dispMsg(app,'c');
                try
                    tmatfile = load(fullfile(app.workingDir,app.editF_mapTemplate.Value));
                    ttemplate = tmatfile.(app.savedAppName);
                    
                    
                catch
                    % for some reason the file cant be opened
                    msgbox('The file cant be read for some reason', 'Chaos!', 'warn');
                end
                
                % load what it is needed from the template file
                    loadVarsFromTemplate(app, ttemplate);
                    
                    % jump user to step 6 trim data tab
                    advanceTab(app, 2);
                    
                    %send a message to user saying that it should skip the frist steps that assign data
                    msgbox({'All Good!!!';...
                            'Now you should skip the fist steps and go to excecute step six (6)';...
                            'consider that if the file selected does not match the CSV columns of this study Exactly';...
                            'crazy stuff will happen'},'You Are Amazing');
                
            else
                dispMsg(app, 'e', 'First select a file.');
            end
        end

        % Button pushed function: button_refreshWorkingDir
        function button_refreshWorkingDirButtonPushed(app, event)
            loadWorkingDirFiles(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ui_computeNullStateMech = uifigure;
            app.ui_computeNullStateMech.Name = 'Motion CSV Data To Node Standard Mapping';
            app.tabg_group = uitabgroup(app.ui_computeNullStateMech);
            app.tab_loadData = uitab(app.tabg_group);
            app.tab_loadData.Title = 'Load Data';
            app.panel_loadData = uipanel(app.tab_loadData);
            app.label_loadFromFile = uilabel(app.panel_loadData);
            app.label_loadFromFile.Text = '';
            app.button_motionDataSelect = uibutton(app.panel_loadData, 'push');
            app.button_motionDataSelect.ButtonPushedFcn = createCallbackFcn(app, @button_motionDataSelectButtonPushed, true);
            app.button_motionDataSelect.Enable = 'off';
            app.button_motionDataSelect.Text = '<<';
            app.chkB_motionDataLock = uicheckbox(app.panel_loadData);
            app.chkB_motionDataLock.ValueChangedFcn = createCallbackFcn(app, @chkB_motionDataLockValueChanged, true);
            app.chkB_motionDataLock.Enable = 'off';
            app.chkB_motionDataLock.Text = 'Lock';
            app.label_filesAvail = uilabel(app.panel_loadData);
            app.label_filesAvail.Text = 'Displaying files in Trial selected plus /share and /res';
            app.listB_filesAvail = uilistbox(app.panel_loadData);
            app.listB_filesAvail.Items = {};
            app.listB_filesAvail.Value = {};
            app.label_motionDataFile = uilabel(app.panel_loadData);
            app.label_motionDataFile.Text = 'Motion Data';
            app.editF_motionDataFile = uieditfield(app.panel_loadData, 'text');
            app.editF_motionDataFile.Editable = 'off';
            app.editF_motionDataFile.Enable = 'off';
            app.label_workingDir = uilabel(app.panel_loadData);
            app.label_workingDir.Text = '';
            app.label_step1 = uilabel(app.panel_loadData);
            app.label_step1.Text = '(1)';
            app.button_browseToMapFile = uibutton(app.panel_loadData, 'push');
            app.button_browseToMapFile.ButtonPushedFcn = createCallbackFcn(app, @button_browseToMapFileButtonPushed, true);
            app.button_browseToMapFile.Enable = 'off';
            app.button_browseToMapFile.Text = {'Load Template'; 'Populate Fields'};
            app.label_divider = uilabel(app.panel_loadData);
            app.label_divider.Text = '----------------------------------------------------------------------------------';
            app.label_existingFile = uilabel(app.panel_loadData);
            app.label_existingFile.Text = '';
            app.button_refreshWorkingDir = uibutton(app.panel_loadData, 'push');
            app.button_refreshWorkingDir.ButtonPushedFcn = createCallbackFcn(app, @button_refreshWorkingDirButtonPushed, true);
            app.button_refreshWorkingDir.Text = 'Refresh';
            app.chkB_lockTrial = uicheckbox(app.panel_loadData);
            app.chkB_lockTrial.ValueChangedFcn = createCallbackFcn(app, @chkB_lockTrialValueChanged, true);
            app.chkB_lockTrial.Text = 'Lock';
            app.label_step0 = uilabel(app.panel_loadData);
            app.label_step0.Text = '(0)';
            app.label_trialsAvail = uilabel(app.panel_loadData);
            app.label_trialsAvail.Text = 'Trials';
            app.listB_trialsAvail = uilistbox(app.panel_loadData);
            app.listB_trialsAvail.Items = {};
            app.listB_trialsAvail.ValueChangedFcn = createCallbackFcn(app, @listB_trialsAvailValueChanged, true);
            app.listB_trialsAvail.Value = {};
            app.button_mapTemplate = uibutton(app.panel_loadData, 'push');
            app.button_mapTemplate.ButtonPushedFcn = createCallbackFcn(app, @button_mapTemplateButtonPushed, true);
            app.button_mapTemplate.Enable = 'off';
            app.button_mapTemplate.Text = '<<';
            app.chkB_mapTemplate = uicheckbox(app.panel_loadData);
            app.chkB_mapTemplate.ValueChangedFcn = createCallbackFcn(app, @chkB_mapTemplateValueChanged, true);
            app.chkB_mapTemplate.Enable = 'off';
            app.chkB_mapTemplate.Text = 'Lock';
            app.label_mapTemplate = uilabel(app.panel_loadData);
            app.label_mapTemplate.Text = 'Template';
            app.editF_mapTemplate = uieditfield(app.panel_loadData, 'text');
            app.editF_mapTemplate.Editable = 'off';
            app.editF_mapTemplate.Enable = 'off';
            app.tab_worldToBody = uitab(app.tabg_group);
            app.tab_worldToBody.Title = 'World to Body';
            app.panel_worldToBody = uipanel(app.tab_worldToBody);
            app.button_getOriginData = uibutton(app.panel_worldToBody, 'push');
            app.button_getOriginData.Text = 'Get Origin Data';
            app.tab_trimData = uitab(app.tabg_group);
            app.tab_trimData.Title = 'Body Transformations';
            app.panel_trimData = uipanel(app.tab_trimData);
            app.tab_refineData = uitab(app.tabg_group);
            app.tab_refineData.Title = 'Refine Data';
            app.panel_refineData = uipanel(app.tab_refineData);
            app.tab_saveAll = uitab(app.tabg_group);
            app.tab_saveAll.Title = 'Save All';
            app.panel_saveAll = uipanel(app.tab_saveAll);
            app.button_saveAllAndExit = uibutton(app.panel_saveAll, 'push');
            app.button_saveAllAndExit.ButtonPushedFcn = createCallbackFcn(app, @button_saveAllAndExitButtonPushed, true);
            app.button_saveAllAndExit.Text = 'SAVE ALL AND EXIT!!!!!!!';
            app.label_savedAs = uilabel(app.panel_saveAll);
            app.label_savedAs.Text = '';
            app.button_saveTemplate = uibutton(app.panel_saveAll, 'push');
            app.button_saveTemplate.ButtonPushedFcn = createCallbackFcn(app, @button_saveTemplateButtonPushed, true);
            app.button_saveTemplate.Text = 'Save as a Template in /share';
            app.label_saveTemplateName = uilabel(app.panel_saveAll);
            app.label_saveTemplateName.Text = '';
            app.button_copyToWorkspace = uibutton(app.panel_saveAll, 'push');
            app.button_copyToWorkspace.ButtonPushedFcn = createCallbackFcn(app, @button_copyToWorkspaceButtonPushed, true);
            app.button_copyToWorkspace.Text = 'Copy results to workspace';
            app.label_copyToWorkspace = uilabel(app.panel_saveAll);
            app.label_copyToWorkspace.Text = 'Var Name';
            app.editF_copyToWorkspace = uieditfield(app.panel_saveAll, 'text');
            app.button_prev = uibutton(app.ui_computeNullStateMech, 'push');
            app.button_prev.ButtonPushedFcn = createCallbackFcn(app, @button_prevButtonPushed, true);
            app.button_prev.Enable = 'off';
            app.button_prev.Text = '<<Prev';
            app.button_next = uibutton(app.ui_computeNullStateMech, 'push');
            app.button_next.ButtonPushedFcn = createCallbackFcn(app, @button_nextButtonPushed, true);
            app.button_next.Enable = 'off';
            app.button_next.Text = 'Next>>';
            app.label_errorMsg = uilabel(app.ui_computeNullStateMech);
            app.label_errorMsg.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = computeNullStateMechGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ui_computeNullStateMech)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ui_computeNullStateMech)
        end
    end
end