classdef renameFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        editF_vesperDir          matlab.ui.control.EditField
        chkB_vesperDirLock       matlab.ui.control.CheckBox
        DatePickerLabel          matlab.ui.control.Label
        DatePicker               matlab.ui.control.DatePicker
        HourSpinnerLabel         matlab.ui.control.Label
        HourSpinner              matlab.ui.control.Spinner
        MinuteSpinnerLabel       matlab.ui.control.Label
        MinuteSpinner            matlab.ui.control.Spinner
        listB_trialsAvail        matlab.ui.control.ListBox
        button_popPairTable      matlab.ui.control.Button
        listB_results            matlab.ui.control.ListBox
        DisplayResultsButton     matlab.ui.control.Button
        SaveChangesButton        matlab.ui.control.Button
        SecSpinnerLabel          matlab.ui.control.Label
        SecSpinner               matlab.ui.control.Spinner
        MilisecEditField_2Label  matlab.ui.control.Label
        MilisecNumEditField      matlab.ui.control.NumericEditField
        button_findDir           matlab.ui.control.Button
    end

    
    properties (Access = private)
        % this variable stores the working directory
        workingDir = ''; 
        vesperDir  = '';
        filetype='E';%irrelevent for now

        %values subject to change and needed in calculation
        setDayOfYear;
        thr;
        tmin;
        tss;
        tms;
        newTime;
        
        newname;
    end
    
    properties (Access = public)
        vesperData = struct(); % Description
        
        
    end
    
    methods (Access = private)
        
        %function loadGlobalFiles(app)
             %TODO -- should trim this to show a ../../dir
                %app.label_workingDir.Text = app.workingDir;

                % dont display the trials availiable
                %[~, tmp] = displayDirRecursively(app.workingDir);
                %app.listB_initialDir.Items = tmp;
       % end

        
        %function setInitialGuiParam(app) 
            
            % load the study directory and display files
           %- loadGlobalFiles(app);

        %end%setInitialGuiParam
        
    end
    
    methods (Access = public)
        
        function dispMsg(app,ttype,tmsg)
             % For log messaging blue
            if(strcmpi(ttype, 'L'))
                app.label_errorMsgf.Text = ['LOG: ' tmsg];
                app.label_errorMsgf.FontColor = [0.00,0.45,0.74];
                
            % for error messages red
            elseif(strcmpi(ttype, 'E'))
                app.label_errorMsgf.Text = ['ERROR: ' tmsg];
                app.label_errorMsgf.FontColor = [0.64,0.08,0.18];
                
            % general propose message black
            elseif(strcmpi(ttype, 'M'))
                app.label_errorMsgf.Text = ['MSG: ' tmsg];
                app.label_errorMsgf.FontColor = [0.00,0.00,0.00];
            
            % for clearing the mesage
            elseif(strcmpi(ttype, 'C'))
                app.label_errorMsgf.Text = '';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function button_vesperDirSelectButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that ta csv is actually selected
            % make sure there is a selection
            if(~isempty(app.listB_dirsAvail.Value))
                % get the selected files in its extended name form
                app.editF_vesperDir.Value = app.listB_dirsAvail.Value;
                app.vesperDir = fullfile(app.workingDir,app.editF_showInitialDir.Value, app.editF_vesperDir.Value);
                
                % lock the value trough the checkbox
                app.chkB_vesperDirLock.Value = 1;
                chkB_vesperDirLockValueChanged(app);
            
                % clear errors if any
                dispMsg(app, 'correct');
            else
                dispMsg(app, 'error', 'Select a file first');
            end 
        end

        % Value changed function: chkB_vesperDirLock
        function chkB_vesperDirLockValueChanged(app, event)
            value = app.chkB_vesperDirLock.Value;
            % for some reason the var value needs to exsist, Abstract defined?
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.button_findDir.Enable = 'off';
               % incStartFlag(app, 1);
            else
                app.button_findDir.Enable = 'on';
                %incStartFlag(app, -1);
                app.editF_vesperDir.Value='';
            end
            %enable pop table
            app.button_popPairTable.Enable='on';


        end

        % Button pushed function: button_popPairTable
        function button_popPairTableButtonPushed(app, event)
            tvesperfiles = cellstr(ls(app.vesperDir));
            tvesperfiles = tvesperfiles(3:end); 
            
            tdate   = NaT(length(tvesperfiles), 1);
            tstop   = NaT(length(tvesperfiles), 1);
            tdur    = zeros(length(tvesperfiles), 1);
            
            %retrieve the date, start and stop times          
            for i = 1: length(tvesperfiles)
                tmp = tvesperfiles{i};
                tidx = strfind(tmp, '_')';
                
                tyy = str2num(['20' tmp(2:tidx(1)-1)]);%how to fix this false expression
                tmm = str2num(tmp(tidx(1)+1:tidx(2)-1));
                tdd = str2num(tmp(tidx(2)+1:tidx(3)-1));
                
                thhdt = str2num(tmp(tidx(3)+1:tidx(4)-1));
                tmidt = str2num(tmp(tidx(4)+1:tidx(5)-1));
                tssdt = floor(str2num(tmp(tidx(5)+1:tidx(6)-1)));
                tmsdt = floor((str2num(tmp(tidx(5)+1:tidx(6)-1)) - tssdt)*1000);
               
                thhst = str2num(tmp(tidx(9)+1:tidx(10)-1));
                tmist = str2num(tmp(tidx(10)+1:tidx(11)-1));
                tssst = floor(str2num(tmp(tidx(11)+1:tidx(12)-1)));
                tmsst = floor((str2num(tmp(tidx(11)+1:tidx(12)-1)) - tssst)*1000);
                
                tdate(i) = datetime(tyy, tmm, tdd, thhdt, tmidt, tssdt,tmsdt);
                tstop(i) = datetime(tyy, tmm, tdd, thhst, tmist, tssst,tmsst);
                
                display(tdate(i))
                display(tstop(i))
            end
            
            %order the table by start time
            [~, tindx] = sort(tdate);

            app.vesperData.tdate  = tdate(tindx);
            app.vesperData.tstop  = tstop(tindx);
            tvesperfiles = tvesperfiles(tindx);

            app.vesperData.files=tvesperfiles;
            
            %populate the availiable trials
            ttrialDir  = app.vesperDir;
            ttrialList = cellstr(ls(ttrialDir));
            ttrialList = ttrialList(3:end);
            app.listB_trialsAvail.Items = ttrialList;
            
        %turn on buttons
        app.DatePicker.Enable='on';
        app.HourSpinner.Enable='on';
        app.MinuteSpinner.Enable='on';
        app.SecSpinner.Enable='on';
        app.MilisecNumEditField.Enable='on';
            
        end

        % Value changed function: DatePicker
        function DatePickerValueChanged(app, event)
            value = app.DatePicker.Value;
            app.setDayOfYear=value;
            app.DisplayResultsButton.Enable='on';
        end

        % Value changed function: HourSpinner
        function HourSpinnerValueChanged(app, event)
            value = app.HourSpinner.Value;
            app.thr=value;
        end

        % Value changed function: MinuteSpinner
        function MinuteSpinnerValueChanged(app, event)
            value = app.MinuteSpinner.Value;
            app.tmin=value;
        end

        % Callback function
        function MilisecEditFieldValueChanged(app, event)
            value = app.MilisecEditField.Value;
            app.tms=value;
        end

        % Button pushed function: DisplayResultsButton
        function DisplayResultsButtonPushed(app, event)
            %prevent default value 0 and empty vector case
            if isempty(app.thr)
               app.thr=0;
            end
            if isempty(app.tmin)
                app.tmin=0;
            end
            if isempty(app.tss)
                app.tss=0;
            end
            if isempty(app.tms)
                app.tms=0;
            end

            hrmissms=duration(app.thr,app.tmin, (app.tss+app.tms/1000));
            app.newTime=app.setDayOfYear+hrmissms;%getting the new time of the first file
            %subtract and get the different to add to the rest of the dates
            diff=app.newTime-app.vesperData.tdate(1);
            
           %used for formating purpose in the for loop
           formatOut = 'yyyy_mm_dd_HH_MM_SS.';%used for formating purpose in the for loop
           app.newname=string([1,length(app.vesperData.tdate)]);
            for i = 1: length(app.vesperData.tdate)
                %alter the values of old times             
                app.vesperData.tdate(i)=app.vesperData.tdate(i)+diff;
                app.vesperData.tstop(i)=app.vesperData.tstop(i)+diff;
                %save the milisec value in another table and make them str
                tdatems=num2str(1000*(app.vesperData.tdate(i).Second-floor(app.vesperData.tdate(i).Second)));
                tstopms=num2str(1000*(app.vesperData.tstop(i).Second-floor(app.vesperData.tstop(i).Second)));

                %produce strings of yy_mm_dd HH_MM_SS.SSS
                tdateymd=datestr(app.vesperData.tdate(i),formatOut);
                tstopymd=datestr(app.vesperData.tstop(i),formatOut);
                strdate=strcat(tdateymd,tdatems);
                strstop=strcat(tstopymd,tstopms);        
                %make a new list of the new names  
                app.newname(i)=strcat(app.filetype,strdate,'-',strstop,'bin',num2str(i),'.csv');
            end
            
            %display the new names in the new listbox listB_results
            app.listB_results.Items=app.newname;
            %turn saving button on 
            app.SaveChangesButton.Enable='on';
        end

        % Value changed function: SecSpinner
        function SecSpinnerValueChanged(app, event)
            value = app.SecSpinner.Value;
            app.tss=value;
        end

        % Button pushed function: SaveChangesButton
        function SaveChangesButtonPushed(app, event)
   
            direc=app.vesperDir;
            for i=1:length(app.vesperData.tdate)
                movefile(fullfile(direc,app.vesperData.files{i}),fullfile(direc,app.newname(i)));%rename in the old folder  
            end
            app.delete;
        end

        % Value changed function: MilisecNumEditField
        function MilisecNumEditFieldValueChanged(app, event)
            value = app.MilisecNumEditField.Value;
            app.tms=value;
        end

        % Button pushed function: button_findDir
        function button_findDirButtonPushed(app, event)
            path=uigetdir;
            app.editF_vesperDir.Value=path;
            app.vesperDir=path; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.editF_vesperDir = uieditfield(app.UIFigure, 'text');
            app.editF_vesperDir.Editable = 'off';
            app.chkB_vesperDirLock = uicheckbox(app.UIFigure);
            app.chkB_vesperDirLock.ValueChangedFcn = createCallbackFcn(app, @chkB_vesperDirLockValueChanged, true);
            app.chkB_vesperDirLock.Text = 'Lock';
            app.DatePickerLabel = uilabel(app.UIFigure);
            app.DatePickerLabel.Enable = 'off';
            app.DatePickerLabel.Text = 'Date Picker';
            app.DatePicker = uidatepicker(app.UIFigure);
            app.DatePicker.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.DatePicker.Enable = 'off';
            app.HourSpinnerLabel = uilabel(app.UIFigure);
            app.HourSpinnerLabel.Enable = 'off';
            app.HourSpinnerLabel.Text = 'Hour';
            app.HourSpinner = uispinner(app.UIFigure);
            app.HourSpinner.Limits = [0 23];
            app.HourSpinner.ValueChangedFcn = createCallbackFcn(app, @HourSpinnerValueChanged, true);
            app.HourSpinner.Enable = 'off';
            app.MinuteSpinnerLabel = uilabel(app.UIFigure);
            app.MinuteSpinnerLabel.Enable = 'off';
            app.MinuteSpinnerLabel.Text = 'Minute';
            app.MinuteSpinner = uispinner(app.UIFigure);
            app.MinuteSpinner.Limits = [0 59];
            app.MinuteSpinner.ValueChangedFcn = createCallbackFcn(app, @MinuteSpinnerValueChanged, true);
            app.MinuteSpinner.Enable = 'off';
            app.listB_trialsAvail = uilistbox(app.UIFigure);
            app.listB_trialsAvail.Items = {};
            app.listB_trialsAvail.Value = {};
            app.button_popPairTable = uibutton(app.UIFigure, 'push');
            app.button_popPairTable.ButtonPushedFcn = createCallbackFcn(app, @button_popPairTableButtonPushed, true);
            app.button_popPairTable.Enable = 'off';
            app.button_popPairTable.Text = 'Display Trials Available';
            app.listB_results = uilistbox(app.UIFigure);
            app.listB_results.Items = {};
            app.listB_results.Value = {};
            app.DisplayResultsButton = uibutton(app.UIFigure, 'push');
            app.DisplayResultsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayResultsButtonPushed, true);
            app.DisplayResultsButton.Enable = 'off';
            app.DisplayResultsButton.Text = 'Display Results';
            app.SaveChangesButton = uibutton(app.UIFigure, 'push');
            app.SaveChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChangesButtonPushed, true);
            app.SaveChangesButton.Enable = 'off';
            app.SaveChangesButton.Text = 'Save Changes';
            app.SecSpinnerLabel = uilabel(app.UIFigure);
            app.SecSpinnerLabel.Enable = 'off';
            app.SecSpinnerLabel.Text = 'Sec';
            app.SecSpinner = uispinner(app.UIFigure);
            app.SecSpinner.Limits = [0 59];
            app.SecSpinner.ValueChangedFcn = createCallbackFcn(app, @SecSpinnerValueChanged, true);
            app.SecSpinner.Enable = 'off';
            app.MilisecEditField_2Label = uilabel(app.UIFigure);
            app.MilisecEditField_2Label.Enable = 'off';
            app.MilisecEditField_2Label.Text = 'Milisec';
            app.MilisecNumEditField = uieditfield(app.UIFigure, 'numeric');
            app.MilisecNumEditField.Limits = [0 999];
            app.MilisecNumEditField.ValueChangedFcn = createCallbackFcn(app, @MilisecNumEditFieldValueChanged, true);
            app.MilisecNumEditField.Enable = 'off';
            app.button_findDir = uibutton(app.UIFigure, 'push');
            app.button_findDir.ButtonPushedFcn = createCallbackFcn(app, @button_findDirButtonPushed, true);
            app.button_findDir.Text = 'Find directory';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = renameFile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end