classdef vesperDataImportGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ui_motionCsvToNodeStdMap      matlab.ui.Figure
        tabg_group                    matlab.ui.container.TabGroup
        tab_loadData                  matlab.ui.container.Tab
        panel_loadData                matlab.ui.container.Panel
        button_vesperDirSelect        matlab.ui.control.Button
        chkB_vesperDirLock            matlab.ui.control.CheckBox
        DisplayingfilesinglobalLabel  matlab.ui.control.Label
        listB_dirsAvail               matlab.ui.control.ListBox
        label_vesperDir               matlab.ui.control.Label
        editF_vesperDir               matlab.ui.control.EditField
        label_step1                   matlab.ui.control.Label
        button_refreshWorkingDir      matlab.ui.control.Button
        label_step0                   matlab.ui.control.Label
        label_cameraObj               matlab.ui.control.Label
        dropD_cameraObj               matlab.ui.control.DropDown
        label_emgFreq                 matlab.ui.control.Label
        dropD_emgFreq                 matlab.ui.control.DropDown
        label_micFreq                 matlab.ui.control.Label
        editF_micFreq                 matlab.ui.control.NumericEditField
        label_emgGain                 matlab.ui.control.Label
        dropD_emgGain                 matlab.ui.control.DropDown
        label_workingDir              matlab.ui.control.Label
        chkB_cameraObjLock            matlab.ui.control.CheckBox
        chkB_emgGainLock              matlab.ui.control.CheckBox
        chkB_emgFreqLock              matlab.ui.control.CheckBox
        chkB_micFreqLock              matlab.ui.control.CheckBox
        button_roi                    matlab.ui.control.Button
        uia_videoRoi                  matlab.ui.control.UIAxes
        tab_pairFiles                 matlab.ui.container.Tab
        panel_pairFiles               matlab.ui.container.Panel
        button_popPairTable           matlab.ui.control.Button
        table_vesperTimes             matlab.ui.control.Table
        button_addSelection           matlab.ui.control.Button
        button_deleteMappedValue      matlab.ui.control.Button
        label_step3                   matlab.ui.control.Label
        button_pairData               matlab.ui.control.Button
        TrialsLabel                   matlab.ui.control.Label
        listB_trialsAvail             matlab.ui.control.ListBox
        button_extractData            matlab.ui.control.Button
        tab_videoSync                 matlab.ui.container.Tab
        panel_videoSync               matlab.ui.container.Panel
        table_trialsMapped            matlab.ui.control.Table
        button_popTrials              matlab.ui.control.Button
        button_genWave                matlab.ui.control.Button
        label_videoFrame              matlab.ui.control.Label
        spinner_videoFrame            matlab.ui.control.Spinner
        uiA_syncSignal                matlab.ui.control.UIAxes
        button_trimToVideo            matlab.ui.control.Button
        button_doneSync               matlab.ui.control.Button
        label_vesperTimeMs            matlab.ui.control.Label
        editF_vesperTimeMs            matlab.ui.control.NumericEditField
        label_vesperTime              matlab.ui.control.Label
        slider_vesperTime             matlab.ui.control.Slider
        chkB_lockTrial                matlab.ui.control.CheckBox
        button_resetAxes              matlab.ui.control.Button
        tab_filterData                matlab.ui.container.Tab
        panel_filterData              matlab.ui.container.Panel
        tab_saveAll                   matlab.ui.container.Tab
        panel_saveAll                 matlab.ui.container.Panel
        button_saveAllAndExit         matlab.ui.control.Button
        button_copyToWorkspace        matlab.ui.control.Button
        label_emgObj                  matlab.ui.control.Label
        listB_emgObj                  matlab.ui.control.ListBox
        button_prev                   matlab.ui.control.Button
        button_next                   matlab.ui.control.Button
        label_errorMsg                matlab.ui.control.Label
    end


%---------------------------- CONSTANT PROPERTIES ---------------------------------%    
    
    properties (Access = private, Constant = true)
        % variables that control app's visibility workflow function
        isTabChar = 'tab_';
        
        % number of initial steps, pair with startedFlag non constant property
        % change this according to the app's workflow before it unlocks the buton next
        numInitialSteps = 5;
        
        %name indicating no camera object is selected
        nullCameraChar = 'N/A';
        
    end
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'vesperDataImportGui';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
        
        % important/ relevant output from this app
        savedVarName = 'vesperDataImport';
        
        % the full app, required to populate this app with a previous session
        % this contains ALL components, even those stored in savedVarName
        savedAppName = 'vesperDataImportGuiApp';
        
        % relevant variables to save in savedVarName
        % savedAppName saves this plus everything else too
        %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
        varsToSaveinStd =  {'appVersion'};
        
    end
    

%---------------------------- VARIABLE PROPERTIES --------------------------------%
    
    properties (Access = private)
        % this variable stores the working directory
        workingDir = '';
        trialDir   = '';
        vesperDir  = '';
        
        % started flag to make sure there is name and directory
        startedFlag = 0;
        
        % tab control variable, start at first tab
        tabControl = 1;
        
        % application tabs, used to move around with buttons, computed at startup
        applicationTabs;
        
        %camera objects
        cameraObjs;
        videoHandle;
        videoRepo;
        
        %camera selected for roi
        camera;
        videoRaw;
        roi;
        
        %sorting table index
        micStartSortInx;
        vesperTimesSelCell;
        trialsMappedSelCell;
        
        micMinusEmg;
        
        videoTimeAxis;
        currVideo;
        micTimeAxis;
        currMic;
        targetTrial;
%         micSyncAxis;
%         videoSyncAxis;
        micPlotHandle;
        vidPlotHandle;
        dataTrimmed = struct();
        
    end
         
    properties (Access = public)
        % where all csv data is saved
        emgFreq;
        emgGain;
        micFreq;
        vesperData = table();
        date;
        micSyncAxis;
        videoSyncAxis;

    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%
    
    methods (Access = private)
        
        function setInitialGuiParam(app)
            % fist turn all panels off
            % get names of all tabs and indexes in app.
            tnames  = fieldnames(app);
            
            % tabs are in the order that they apear in the gui in sequence
            %TODO -- if tabs have multiple children this wont work
            app.applicationTabs = tnames(contains(tnames,app.isTabChar));
            
            % disable all panels
            for(i=1: length(app.applicationTabs))
                app.(app.applicationTabs{i}).Children.Visible = 'off';
            end
            
            % the first pannel and tab controlled by class tab control
            app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
            app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            
            % load the study directory and display files
            loadGlobalFiles(app);
            loadVideoHandlers(app);
            
            %remove axis from ROI image
            axis(app.uia_videoRoi,'off');
        end%setInitialGuiParam
%-----------------------------------------------------------------------------------%
        
        function loadGlobalFiles(app)   
                %TODO -- should trim this to show a ../../dir
                app.label_workingDir.Text = app.workingDir;

                % dont display the trials availiable
                [~, tmp] = displayDirRecursively(fullfile(app.workingDir, 'global'));
                app.listB_dirsAvail.Items = tmp;
                
        end%loadGlobalFiles
%-----------------------------------------------------------------------------------%

        function findAppInstance(app)
            % see if there is an instance of this app in the working directory
            if(~(isfile(fullfile(app.trialDir, [app.savedAppName '.mat']))))
                app.label_existingFile.Text = {['There is no ' app.savedAppName ' File in the Working Directory.'] 'Saving will create a new one.'};
                loadEmptyAppState(app);
            else
                % try to open the file
                try
                    % disable wargings from saving stuff such as uia components
                    warning('off');
                    tmatfile = load(fullfile(app.trialDir, [app.savedAppName '.mat']));
                    loadedFile = tmatfile.(app.savedAppName);
                    
                    % turn warnings back on
                    warning('on');
                    %TODO -- this is a matlab bug
                    warning off MATLAB:subscripting:noSubscriptsSpecified;
                    
                % send a message and ask the user to take action 
                catch
                    msgbox({'There was an error loading the file, app wil close'; ...
                            'If probem persist you need to delete the saved mat file'}, 'Error','error');
                    delete(app);
                end
                % load variables to populate the instance of this app
                app.label_existingFile.Text = {['There is a ' app.savedAppName ' File in the Working Directory.'] 'Saving will overwrite it.'};
                loadAppState(app, loadedFile);
            end
        end%findAppInstance
%-----------------------------------------------------------------------------------%

        function loadEmptyAppState(app)
            
        end%loadAppState
%-----------------------------------------------------------------------------------%

        function loadAppState(app)
  
        end%loadAppState
%-----------------------------------------------------------------------------------%
        
        function resetSyncPlot(app)
            xlabel(app.uiA_syncSignal, 'Time (s)')
            ylabel(app.uiA_syncSignal, 'Norm Amplitude')
            app.uiA_syncSignal.YLim = [0 1.1];
            app.uiA_syncSignal.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1];
            app.uiA_syncSignal.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'; '1.1'};
            app.uiA_syncSignal.Position = [298 11 725 345];
            app.uiA_syncSignal.XLimMode = 'auto';
  
        end%loadAppState
%-----------------------------------------------------------------------------------%

        function loadVideoHandlers(app)    
            % see if there is an instance of this app in the working directory
            if(~(isfile(fullfile(app.workingDir, 'share', [videoTrialHandlerGui.savedAppName '.mat']))))
                 msgbox({['There is no ' videoTrialHandlerGui.savedAppName ' File in the share Working Directory.'] 'You must create video handle  first'}, 'Error','error');
            else
                % try to open the file
                try
                    % just load the camera objects
                    tmp = fullfile(app.workingDir, 'share', [videoTrialHandlerGui.savedAppName '.mat']);
                    tstruct = load(tmp, videoTrialHandlerGui.savedVarName);

                    app.videoHandle = tstruct.(videoTrialHandlerGui.savedVarName).(videoTrialHandlerGui.varsToSaveinStd{1});
                    app.videoRepo   = tstruct.(videoTrialHandlerGui.savedVarName).(videoTrialHandlerGui.varsToSaveinStd{2});

                % send a message and ask the user to take action 
                catch
                    msgbox({'There was an error loading the file, app wil close'; ...
                            'If probem persist you need to delete the saved mat file'}, 'Error','error');
                    delete(app);
                end
                % load variables to populate the instance of this app
                %TODO -- thisis kinda hard coded too
                app.dropD_cameraObj.Items = unique(app.videoHandle.cameraObjName);
            end
        end%loadVideoHandlers
%-----------------------------------------------------------------------------------%

        function incStartFlag(app, cnt)
            % increase or decrease the start flag with bound checking
            % this ommits the value of cnt and only uses its sign.
            if(cnt>0 && app.startedFlag<app.numInitialSteps)
                app.startedFlag = app.startedFlag + 1;
            elseif(cnt<0 && app.startedFlag>0)
                app.startedFlag = app.startedFlag - 1;
            end
            
            % determine if the start is done and enable prev and next buttons
            if(app.startedFlag>=app.numInitialSteps)
                app.button_next.Enable = 'on';
                app.button_prev.Enable = 'on';
            else
                app.button_next.Enable = 'off';
                app.button_prev.Enable = 'off';
            end
        end%incStartFlag
%-----------------------------------------------------------------------------------%
        
        function advanceTab(app, cnt)
            % first check if cnt will request a tab out of range
            if((app.tabControl+cnt)>length(app.applicationTabs) || (app.tabControl+cnt)<1)
                dispMsg(app, 'e', 'tab change request out of range');
            else
                % clear errors if any
                dispMsg(app, 'c');
                
                % disable the current panel
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'off';
                
                % increase tab control and activate next panel and tab
                app.tabControl = app.tabControl+cnt;
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
                app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            end
        end%advanceTab
%-----------------------------------------------------------------------------------%

        function s = varsToSaveinStdFcn(app)
            % saves just the relevant variables that come out of this class not to save ui elements
            for(i = 1:length(app.varsToSaveinStd))
                s.(app.varsToSaveinStd{i}) = app.(app.varsToSaveinStd{i});
            end                
        end%varsToSaveinStdFcn
%-----------------------------------------------------------------------------------%

        function setRoiValue()
            % saves roi values from figure upon closing figure into app variable
            %app.roi = troi;
            %display(app.roi);
           display('hellow world'); 
        end%varsToSaveinStdFcn
%-----------------------------------------------------------------------------------%  

        function loadVarsFromTemplate(app, ttemplate)
            % step 2 is indifferent so run it as-is
            button_popPairTableButtonPushed(app);
            
            % technically colum 3 is already populated from step 2, whatever.
            % copy the node assignment from the template experiment
            app.table_vesperTimes.Data = ttemplate.table_vesperTimes.Data;
            
            % compute step 4 and five
            % the user can do this technically but it should be ready so do it automatically and save time
            button_computeMapMatrixPushed(app);
            button_populateTrimNanButtonPushed(app);
        end%loadVarsFromTemplate
%-----------------------------------------------------------------------------------%

        function dispMsg(app, ttype, tmsg)
            % For log messaging blue
            if(strcmpi(ttype, 'L'))
                app.label_errorMsg.Text = ['LOG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.45,0.74];
                
            % for error messages red
            elseif(strcmpi(ttype, 'E'))
                app.label_errorMsg.Text = ['ERROR: ' tmsg];
                app.label_errorMsg.FontColor = [0.64,0.08,0.18];
                
            % general propose message black
            elseif(strcmpi(ttype, 'M'))
                app.label_errorMsg.Text = ['MSG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.00,0.00];
            
            % for clearing the mesage
            elseif(strcmpi(ttype, 'C'))
                app.label_errorMsg.Text = '';
            end
        end%dispMsg
%-----------------------------------------------------------------------------------%

    end%private methods

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tworkingDir)
            app.workingDir = tworkingDir;
            setInitialGuiParam(app);
        end

        % Button pushed function: button_next
        function button_nextButtonPushed(app, event)
            % move to next tab
            advanceTab(app, 1);
        end

        % Button pushed function: button_prev
        function button_prevButtonPushed(app, event)
            % move to previous tab
            advanceTab(app, -1);
        end

        % Value changed function: chkB_vesperDirLock
        function chkB_vesperDirLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_vesperDirLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.button_vesperDirSelect.Enable = 'off';
                incStartFlag(app, 1);
            else
                app.button_vesperDirSelect.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Button pushed function: button_vesperDirSelect
        function button_vesperDirSelectButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that ta csv is actually selected
            % make sure there is a selection
            if(~isempty(app.listB_dirsAvail.Value))
                % get the selected files in its extended name form
                app.editF_vesperDir.Value = app.listB_dirsAvail.Value;
                app.vesperDir = fullfile(app.workingDir, 'global', app.editF_vesperDir.Value);
                
                % lock the value trough the checkbox
                app.chkB_vesperDirLock.Value = 1;
                chkB_vesperDirLockValueChanged(app);
            
                % clear errors if any
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'Select a file first');
            end 
        end

        % Button pushed function: button_popPairTable
        function button_popPairTableButtonPushed(app, event)
            %parse the names of the vesper files
            tvesperfiles = cellstr(ls(app.vesperDir));
            tvesperfiles = tvesperfiles(3:end);
            
            tdate   = NaT(length(tvesperfiles), 1);
            tstop   = NaT(length(tvesperfiles), 1);
            tdur    = zeros(length(tvesperfiles), 1);

            %retrieve the date, start and stop times          
            for(i = 1: length(tvesperfiles))
                tmp = tvesperfiles{i};
                tidx = findstr(tmp, '_')';
                
                tyy = str2num(['20' tmp(2:tidx(1)-1)]);
                tmm = str2num(tmp(tidx(1)+1:tidx(2)-1));
                tdd = str2num(tmp(tidx(2)+1:tidx(3)-1));
                
                thhdt = str2num(tmp(tidx(3)+1:tidx(4)-1));
                tmidt = str2num(tmp(tidx(4)+1:tidx(5)-1));
                tssdt = floor(str2num(tmp(tidx(5)+1:tidx(6)-1)));
                tmsdt = floor((str2num(tmp(tidx(5)+1:tidx(6)-1)) - tssdt)*1000);
                
                thhst = str2num(tmp(tidx(9)+1:tidx(10)-1));
                tmist = str2num(tmp(tidx(10)+1:tidx(11)-1));
                tssst = floor(str2num(tmp(tidx(11)+1:tidx(12)-1)));
                tmsst = floor((str2num(tmp(tidx(11)+1:tidx(12)-1)) - tssst)*1000);
                
                tdate(i) = datetime(tyy, tmm, tdd, thhdt, tmidt, tssdt, tmsdt);
                tstop(i) = datetime(tyy, tmm, tdd, thhst, tmist, tssst, tmsst);
                tdur(i)  = thhdt*(60*60*1000)+tmidt*(60*1000)+tssdt*(1000)+tmsdt;
            end
            
            %order the table by start time
            [~, tindx] = sort(tdate);

            tdate  = tdate(tindx);
            tstop  = tstop(tindx);
            tdur   = tdur(tindx);
            tvesperfiles = tvesperfiles(tindx);
            
            temgfilesidx = find(contains(tvesperfiles,'E'));
            tmicfilesidx = find(contains(tvesperfiles,'U'));
            
            %get the difference in times
            app.vesperData.emgFile  = tvesperfiles(temgfilesidx);
            app.vesperData.micFile  = tvesperfiles(tmicfilesidx);
            app.vesperData.micStart = tdate(tmicfilesidx);
            app.vesperData.micEnd   = tstop(tmicfilesidx);
            app.micMinusEmg         = (tdur(tmicfilesidx)-tdur(temgfilesidx))/1000;
 
            %fill the table
            tmp = cell(length(app.vesperData.micStart),1);
            tmp(:) = {''};
            app.table_vesperTimes.Data = tmp;
            app.table_vesperTimes.Data(:,2) = cellstr(datestr(app.vesperData.micStart));
            app.table_vesperTimes.Data(:,3) = cellstr(datestr(app.vesperData.micEnd));
            
            %populate the availiable trials
            ttrialDir  = fullfile(app.workingDir, 'trial');
            ttrialList = cellstr(ls(ttrialDir));
            ttrialList = ttrialList(3:end);
            app.listB_trialsAvail.Items = ttrialList;
            
            %enable buttons
            app.button_addSelection.Enable      = 'on';
            app.button_deleteMappedValue.Enable = 'on';
            app.button_pairData.Enable          = 'on';
            
        end

        % Callback function
        function table_trialsAvailCellSelection(app, event)
            % for some reason it does not like to be directly assigned to private
            indices = event.Indices(:,1);
            
        end

        % Cell selection callback: table_vesperTimes
        function table_vesperTimesCellSelection(app, event)
            % for some reason it does not like to be directly assigned to private var
            indices = event.Indices(:,1);
            app.vesperTimesSelCell = indices(1,1);
        end

        % Button pushed function: button_saveAllAndExit
        function button_saveAllAndExitButtonPushed(app, event)
            % disable wargings from saving stuff such as uia components
            %TODO -- just dont try to save them to prevent warning
            warning('off');
            
            % make a struct of what I want to save to that they keep those variable names
            tmp.(app.savedAppName) = app;
            tmp.(app.savedVarName) = varsToSaveinStdFcn(app);
            
            % save file
            %TODO -- does this needs a try catch?
            save(fullfile(app.trialDir, [app.savedAppName '.mat']), '-struct', 'tmp');
            app.label_savedAs.Text = fullfile(app.trialDir, [app.savedAppName '.mat']);
            app.label_existingFile.Text = {['There is a ' app.savedAppName ' File in the Working Directory.'] 'Saving will overwrite it.'};
            loadGlobalFiles(app);
            
            % alert the user it is done
            msgbox({'All Saved!!!';'You can keep modfying or just close the window'},'Success');
            
            % turn warnings back on
            warning('on');
            %TODO -- this is a matlab bug
            warning off MATLAB:subscripting:noSubscriptsSpecified;
        end

        % Button pushed function: button_refreshWorkingDir
        function button_refreshWorkingDirButtonPushed(app, event)
            loadGlobalFiles(app);
        end

        % Value changed function: chkB_cameraObjLock
        function chkB_cameraObjLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_cameraObjLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.dropD_cameraObj.Enable = 'off';
                app.button_roi.Enable      = 'off';
                incStartFlag(app, 1);
            else
                app.dropD_cameraObj.Enable = 'on';
                app.button_roi.Enable      = 'on';
                incStartFlag(app, -1);
            end
        end

        % Value changed function: dropD_cameraObj
        function dropD_cameraObjValueChanged(app, event)
            value = app.dropD_cameraObj.Value;
            % when a camera object is selected, lock it
            if(~strcmp(value,app.nullCameraChar))
                app.chkB_cameraObjLock.Value = 1;
                chkB_cameraObjLockValueChanged(app)
            end
        end

        % Value changed function: dropD_emgGain
        function dropD_emgGainValueChanged(app, event)
            value = app.dropD_emgGain.Value;
            % when a emg gain is selected, lock it
            app.chkB_emgGainLock.Value = 1;
            chkB_emgGainLockValueChangedd(app)
        end

        % Value changed function: chkB_emgGainLock
        function chkB_emgGainLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_emgGainLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.dropD_emgGain.Enable = 'off';
                app.emgGain = str2num(app.dropD_emgGain.Value);
                incStartFlag(app, 1);
            else
                app.dropD_emgGain.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Value changed function: dropD_emgFreq
        function dropD_emgFreqValueChanged(app, event)
            value = app.dropD_emgFreq.Value;
            % when a emg frew is selected, lock it
            app.chkB_emgFreqLock.Value = 1;
            chkB_emgGainLockValueChangedd(app)
        end

        % Value changed function: chkB_emgFreqLock
        function chkB_emgFreqLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_emgFreqLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.dropD_emgFreq.Enable = 'off';
                app.emgFreq = str2num(app.dropD_emgFreq.Value);
                incStartFlag(app, 1);
            else
                app.dropD_emgFreq.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Value changed function: chkB_micFreqLock
        function chkB_micFreqLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_micFreqLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.editF_micFreq.Enable = 'off';
                app.micFreq = app.editF_micFreq.Value;
                incStartFlag(app, 1);
            else
                app.editF_micFreq.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Button pushed function: button_roi
        function button_roiButtonPushed(app, event)
            %select a random video taken with the selected camera
            tcamidx = contains(app.videoHandle.cameraObjName, app.dropD_cameraObj.Value);
            tvidlist = app.videoHandle.filename(tcamidx);
            
            % read video file
            %tvideoRaw = VideoReader(fullfile(app.videoRepo, tvidlist(floor(length(tvidlist)*rand(1,1))+1)));
            tvideoRaw = VideoReader('C:\Users\alberto-bortoni\Desktop\sandbox\carolliaVid\global\test_video_Light.mp4');
            tvideoRaw.CurrentTime = tvideoRaw.Duration*rand(1,1);
            
            % make a figure
            thandl = figure();
            currAxes = axes(thandl);
            %addprop(currAxes,'troi');
            vidFrame = readFrame(tvideoRaw);
            image(vidFrame, 'Parent', currAxes);
            axis equal;
            
            %allow user do dra the ROI
            troi = drawrectangle('Parent', currAxes, 'Rotatable', false, 'Color',[1 0 0]);
            app.roi = floor(troi.Position);
            close(thandl);
            
            %show image from ROI
            k=imcrop(vidFrame,app.roi);
            image(k,'Parent', app.uia_videoRoi);
            
            %disable button
            app.chkB_cameraObjLock.Value = 1;
            chkB_cameraObjLockValueChanged(app);
            
        end

        % Button pushed function: button_addSelection
        function button_addSelectionButtonPushed(app, event)
            %add selection to pair to vesper
            if(~isempty(app.listB_trialsAvail.Value) && ~isempty(app.vesperTimesSelCell) && strcmp(app.table_vesperTimes.Data(app.vesperTimesSelCell,1),''))
                % assign value to table
                app.table_vesperTimes.Data(app.vesperTimesSelCell,1) =  {app.listB_trialsAvail.Value};
                app.listB_trialsAvail.Items(contains(app.listB_trialsAvail.Items, app.listB_trialsAvail.Value)) = [];
                
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'select a trial and a target cell on table');
            end
        end

        % Button pushed function: button_deleteMappedValue
        function button_deleteMappedValueButtonPushed(app, event)
           %add selection to pair to vesper
            if(~isempty(app.vesperTimesSelCell))
                % assign value to table
                app.listB_trialsAvail.Items = [app.listB_trialsAvail.Items, app.table_vesperTimes.Data(app.vesperTimesSelCell,1)];
                app.table_vesperTimes.Data(app.vesperTimesSelCell,1) =  {''};
                
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'select a target cell on table');
            end 
        end

        % Button pushed function: button_pairData
        function button_pairDataButtonPushed(app, event)
            %disable buttons
            app.button_addSelection.Enable      = 'off';
            app.button_deleteMappedValue.Enable = 'off';
            app.button_popPairTable.Enable      = 'off';
            
            % create trial on table
            app.vesperData.trial = app.table_vesperTimes.Data(:,1);
            app.button_extractData.Enable = 'on';
            app.button_pairData.Enable    = 'off';
            
        end

        % Button pushed function: button_extractData
        function button_extractDataButtonPushed(app, event)
            %start struct with first array
            dispMsg(app, 'l', 'Extracting, wait.');
            tdata = struct();
            tmp   = struct();
            tmp.emg      = readtable(fullfile(app.vesperDir, app.vesperData.emgFile{1}), 'ReadVariableNames', true);
            [tmp.mic, ~] = audioread(fullfile(app.vesperDir, app.vesperData.micFile{1}));
            tdata = tmp;
            
            %fill the rest of the table
            for i = 2: size(app.vesperData,1)
                tmp.emg      = readtable(fullfile(app.vesperDir, app.vesperData.emgFile{i}), 'ReadVariableNames', true);
                [tmp.mic, ~] = audioread(fullfile(app.vesperDir, app.vesperData.micFile{i}));
                tdata = [tdata; tmp];
            end
            
            app.vesperData.dataRaw = tdata;
            
            dispMsg(app, 'l', 'Done extracting');
            %TODO -- trim data from mic and emg to have the same window of time
            
        end

        % Button pushed function: button_popTrials
        function button_popTrialsButtonPushed(app, event)
            %populate table
            ttrials = app.vesperData.trial;
            tmp = cell(length(ttrials),1);
            tmp(:) = {''};
            app.table_trialsMapped.Data = [ttrials, tmp];
            
            %enable buttons
            app.button_popTrials.Enable   = 'off';
            app.chkB_lockTrial.Enable     = 'on';
            app.table_trialsMapped.Enable = 'on';
            
            tdata = struct();
            tdata.emg     = [];
            tdata.emgTime = [];
            tdata.mic     = [];
            tdata.micTime = [];
            tmp   = struct();
            for i = 2: size(app.vesperData,1)
                tmp.emg     = [];
                tmp.emgTime = [];
                tmp.mic     = [];
                tmp.micTime = [];
                tdata = [tdata; tmp];
            end
            app.vesperData.data = tdata;
            
        end

        % Button pushed function: button_genWave
        function button_genWaveButtonPushed(app, event)
            %first make sure that there is a cell selection
            %get trial
%                 app.targetTrial = app.table_trialsMapped.Data(app.trialsMappedSelCell,1);
            dispMsg(app, 'l', 'Thinking, hold on');
            %get the habdle of this video on this trail and on selected camera
            tcamidx  = contains(app.videoHandle.cameraObjName, app.dropD_cameraObj.Value);
            tcamidx  = (contains(app.videoHandle.trial, app.targetTrial) & tcamidx);
            tvidlist = app.videoHandle(tcamidx,:);
            tmicidx  = contains(app.vesperData.trial, app.targetTrial);
            
            
            %tmake sure that there is only video that matches otherwise video handle was not done correctly
            if(size(tvidlist,1)~= 1)
                dispMsg(app, 'e', 'there is more than one video matching, not possible. Redo video handler gui');
            else
                tvideoRaw = VideoReader('C:\Users\alberto-bortoni\Desktop\sandbox\carolliaVid\global\test_video_Light.mp4');
                %tvideoRaw = VideoReader(fullfile(app.videoRepo, char(tvidlist.filename)));
            
            
                tmp = readFrame(tvideoRaw);
                tframe = mean(tmp(app.roi(1):(app.roi(1)+app.roi(3)), app.roi(2):(app.roi(2)+app.roi(4))), 'all'); 
            
                while hasFrame(tvideoRaw)
                    tmp = readFrame(tvideoRaw);
                    tframe = [tframe; mean(tmp(app.roi(1):(app.roi(1)+app.roi(3)), app.roi(2):(app.roi(2)+app.roi(4))), 'all')];
                end
                tidx = tframe>(floor(max(tframe)/2-min(tframe)/2));
                tframe(~tidx) = 0;
                tframe( tidx) = 1;
                
                app.currVideo     = tframe;
                app.currMic       = normalize(app.vesperData.dataRaw(tmicidx).mic, 'range');
                app.videoTimeAxis = linspace(0, length(app.currVideo)/tvideoRaw.FrameRate, length(app.currVideo));
                app.micTimeAxis   = linspace(0, length(app.currMic)/app.micFreq, length(app.currMic));
                app.micSyncAxis   = app.micTimeAxis;
                app.videoSyncAxis = app.videoTimeAxis;
                
                cla(app.uiA_syncSignal,'reset');
                resetSyncPlot(app);
                app.micPlotHandle = plot(app.uiA_syncSignal, app.micSyncAxis, app.currMic);
                app.micPlotHandle.XDataSource = 'app.micSyncAxis';
                hold(app.uiA_syncSignal, 'on');
                app.vidPlotHandle = plot(app.uiA_syncSignal, app.videoSyncAxis, app.currVideo);
                app.vidPlotHandle.XDataSource = 'app.videoSyncAxis';
                hold(app.uiA_syncSignal, 'off');
                
                app.editF_vesperTimeMs.Value = 0;
                app.slider_vesperTime.Value  = 0;
                
                dispMsg(app, 'l', 'Wave generated, move the times, then trim');
            end

        end

        % Cell selection callback: table_trialsMapped
        function table_trialsMappedCellSelection(app, event)
            indices = event.Indices;
            app.trialsMappedSelCell = indices(1,1);
        end

        % Value changed function: chkB_lockTrial
        function chkB_lockTrialValueChanged(app, event)
            value = app.chkB_lockTrial.Value;
            app.targetTrial = app.table_trialsMapped.Data(app.trialsMappedSelCell,1);
            
            if(~isempty(app.trialsMappedSelCell))
                %enable buttons
                if(value)
                    app.button_genWave.Enable     = 'on';
                    app.button_trimToVideo.Enable = 'on';
                    app.button_doneSync.Enable    = 'on';
                    app.table_trialsMapped.Enable = 'off';
                    %app.spinner_videoFrame.Enable = 'on';
                    app.editF_vesperTimeMs.Enable = 'on';
                    app.slider_vesperTime.Enable  = 'on';
                    app.button_resetAxes.Enable   = 'on';
                else
                    app.button_genWave.Enable     = 'off';
                    app.button_trimToVideo.Enable = 'off';
                    app.button_doneSync.Enable    = 'off';
                    app.table_trialsMapped.Enable = 'on';
                    %app.spinner_videoFrame.Enable = 'off';
                    app.editF_vesperTimeMs.Enable = 'off';
                    app.slider_vesperTime.Enable  = 'off';
                    app.button_resetAxes.Enable   = 'off';
                end
                dispMsg(app, 'c');
                
            else
                dispMsg(app, 'e', 'select a target cell on table');
            end 
        end

        % Value changed function: slider_vesperTime
        function slider_vesperTimeValueChanged(app, event)
            value = app.slider_vesperTime.Value;
            app.micSyncAxis = app.micTimeAxis + ((app.micTimeAxis(end)*app.slider_vesperTime.Value/100) + app.editF_vesperTimeMs.Value/1000);
            refreshdata(app.micPlotHandle, 'caller')
        end

        % Value changed function: editF_vesperTimeMs
        function editF_vesperTimeMsValueChanged(app, event)
            value = app.editF_vesperTimeMs.Value;
            app.micSyncAxis = app.micTimeAxis + ((app.micTimeAxis(end)*app.slider_vesperTime.Value/100) + app.editF_vesperTimeMs.Value/1000);
            refreshdata(app.micPlotHandle, 'caller')
        end

        % Button pushed function: button_resetAxes
        function button_resetAxesButtonPushed(app, event)
            resetSyncPlot(app);
        end

        % Button pushed function: button_trimToVideo
        function button_trimToVideoButtonPushed(app, event)
            
            
            tidx  = contains(app.vesperData.trial, app.targetTrial);
            
            tmicstart = find(app.micSyncAxis>0, 1, 'first');
            tmicstop  = find(app.micSyncAxis<app.videoTimeAxis(end), 1, 'last');
            
            %fill the rest of the table
            app.vesperData.dataRaw(tidx).micTrimLim = [tmicstart, tmicstop];
            app.vesperData.data(tidx).micTime = app.micTimeAxis(tmicstart:tmicstop)' - app.micTimeAxis(tmicstart);
            app.vesperData.data(tidx).mic     = app.vesperData.dataRaw(tidx).mic(tmicstart:tmicstop);
            
            temgtime = linspace(0, size(app.vesperData.dataRaw(tidx).emg,1)/app.emgFreq, size(app.vesperData.dataRaw(tidx).emg,1))' + app.micMinusEmg(tidx);
            temgstart = find(temgtime>app.micTimeAxis(tmicstart), 1, 'first');
            temgstop  = find(temgtime<app.micTimeAxis(tmicstop),  1, 'last');
            app.vesperData.data(tidx).emgTime = temgtime(temgstart:temgstop) - app.micTimeAxis(tmicstart);
            app.vesperData.dataRaw(tidx).emgTrimLim = [temgstart, temgstop];
            
            app.vesperData.data(tidx).emg = app.vesperData.dataRaw(tidx).emg(temgstart:temgstop,:);
  
            assignin('base','ves', app.vesperData);
            dispMsg(app, 'l', 'Trimmed, select done and get a new trial');

        end

        % Button pushed function: button_doneSync
        function button_doneSyncButtonPushed(app, event)
            app.table_trialsMapped.Data(app.trialsMappedSelCell,2) = {true};
            app.chkB_lockTrial.Value = 0;
            
            cla(app.uiA_syncSignal,'reset');
            resetSyncPlot(app);
            app.editF_vesperTimeMs.Value = 0;
            app.slider_vesperTime.Value  = 0;
            
            chkB_lockTrialValueChanged(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ui_motionCsvToNodeStdMap = uifigure;
            app.ui_motionCsvToNodeStdMap.Name = 'Motion CSV Data To Node Standard Mapping';
            app.tabg_group = uitabgroup(app.ui_motionCsvToNodeStdMap);
            app.tab_loadData = uitab(app.tabg_group);
            app.tab_loadData.Title = 'Load Data';
            app.panel_loadData = uipanel(app.tab_loadData);
            app.button_vesperDirSelect = uibutton(app.panel_loadData, 'push');
            app.button_vesperDirSelect.ButtonPushedFcn = createCallbackFcn(app, @button_vesperDirSelectButtonPushed, true);
            app.button_vesperDirSelect.Text = '<<';
            app.chkB_vesperDirLock = uicheckbox(app.panel_loadData);
            app.chkB_vesperDirLock.ValueChangedFcn = createCallbackFcn(app, @chkB_vesperDirLockValueChanged, true);
            app.chkB_vesperDirLock.Text = 'Lock';
            app.DisplayingfilesinglobalLabel = uilabel(app.panel_loadData);
            app.DisplayingfilesinglobalLabel.Text = 'Displaying files in /global';
            app.listB_dirsAvail = uilistbox(app.panel_loadData);
            app.listB_dirsAvail.Items = {};
            app.listB_dirsAvail.Value = {};
            app.label_vesperDir = uilabel(app.panel_loadData);
            app.label_vesperDir.Text = 'Vesper Raw Dir';
            app.editF_vesperDir = uieditfield(app.panel_loadData, 'text');
            app.editF_vesperDir.Editable = 'off';
            app.label_step1 = uilabel(app.panel_loadData);
            app.label_step1.Text = '(1)';
            app.button_refreshWorkingDir = uibutton(app.panel_loadData, 'push');
            app.button_refreshWorkingDir.ButtonPushedFcn = createCallbackFcn(app, @button_refreshWorkingDirButtonPushed, true);
            app.button_refreshWorkingDir.Text = 'Refresh';
            app.label_step0 = uilabel(app.panel_loadData);
            app.label_step0.Text = '(0)';
            app.label_cameraObj = uilabel(app.panel_loadData);
            app.label_cameraObj.Text = 'Camera Obj';
            app.dropD_cameraObj = uidropdown(app.panel_loadData);
            app.dropD_cameraObj.Items = {};
            app.dropD_cameraObj.ValueChangedFcn = createCallbackFcn(app, @dropD_cameraObjValueChanged, true);
            app.dropD_cameraObj.Value = {};
            app.label_emgFreq = uilabel(app.panel_loadData);
            app.label_emgFreq.Text = 'EMG Freq';
            app.dropD_emgFreq = uidropdown(app.panel_loadData);
            app.dropD_emgFreq.Items = {'125Hz', '250Hz', '500Hz', '1kHz', '2kHz'};
            app.dropD_emgFreq.ItemsData = {'125', '250', '500', '1000', '2000', ''};
            app.dropD_emgFreq.ValueChangedFcn = createCallbackFcn(app, @dropD_emgFreqValueChanged, true);
            app.dropD_emgFreq.Value = '2000';
            app.label_micFreq = uilabel(app.panel_loadData);
            app.label_micFreq.Text = 'Mic Freq';
            app.editF_micFreq = uieditfield(app.panel_loadData, 'numeric');
            app.editF_micFreq.Limits = [0 80000];
            app.editF_micFreq.ValueDisplayFormat = '%.0f';
            app.editF_micFreq.Value = 65536;
            app.label_emgGain = uilabel(app.panel_loadData);
            app.label_emgGain.Text = {'EMG Gain'; ''};
            app.dropD_emgGain = uidropdown(app.panel_loadData);
            app.dropD_emgGain.Items = {'x1', 'x2', 'x3', 'x4', 'x6', 'x8', 'x12'};
            app.dropD_emgGain.ItemsData = {'1', '2', '3', '4', '6', '8', '12'};
            app.dropD_emgGain.ValueChangedFcn = createCallbackFcn(app, @dropD_emgGainValueChanged, true);
            app.dropD_emgGain.Value = '6';
            app.label_workingDir = uilabel(app.panel_loadData);
            app.label_workingDir.Text = '';
            app.chkB_cameraObjLock = uicheckbox(app.panel_loadData);
            app.chkB_cameraObjLock.ValueChangedFcn = createCallbackFcn(app, @chkB_cameraObjLockValueChanged, true);
            app.chkB_cameraObjLock.Text = 'Lock';
            app.chkB_emgGainLock = uicheckbox(app.panel_loadData);
            app.chkB_emgGainLock.ValueChangedFcn = createCallbackFcn(app, @chkB_emgGainLockValueChanged, true);
            app.chkB_emgGainLock.Text = 'Lock';
            app.chkB_emgFreqLock = uicheckbox(app.panel_loadData);
            app.chkB_emgFreqLock.ValueChangedFcn = createCallbackFcn(app, @chkB_emgFreqLockValueChanged, true);
            app.chkB_emgFreqLock.Text = 'Lock';
            app.chkB_micFreqLock = uicheckbox(app.panel_loadData);
            app.chkB_micFreqLock.ValueChangedFcn = createCallbackFcn(app, @chkB_micFreqLockValueChanged, true);
            app.chkB_micFreqLock.Text = 'Lock';
            app.button_roi = uibutton(app.panel_loadData, 'push');
            app.button_roi.ButtonPushedFcn = createCallbackFcn(app, @button_roiButtonPushed, true);
            app.button_roi.Text = 'ROI';
            app.uia_videoRoi = uiaxes(app.panel_loadData);
            title(app.uia_videoRoi, 'ROI Selected')
            xlabel(app.uia_videoRoi, 'X')
            ylabel(app.uia_videoRoi, 'Y')
            app.tab_pairFiles = uitab(app.tabg_group);
            app.tab_pairFiles.Title = 'Pair Files';
            app.panel_pairFiles = uipanel(app.tab_pairFiles);
            app.button_popPairTable = uibutton(app.panel_pairFiles, 'push');
            app.button_popPairTable.ButtonPushedFcn = createCallbackFcn(app, @button_popPairTableButtonPushed, true);
            app.button_popPairTable.Text = 'Populate Table (2)';
            app.table_vesperTimes = uitable(app.panel_pairFiles);
            app.table_vesperTimes.ColumnName = {'trial'; 'Mic Start'; 'Mic End'};
            app.table_vesperTimes.RowName = {};
            app.table_vesperTimes.ColumnEditable = false;
            app.table_vesperTimes.CellSelectionCallback = createCallbackFcn(app, @table_vesperTimesCellSelection, true);
            app.button_addSelection = uibutton(app.panel_pairFiles, 'push');
            app.button_addSelection.ButtonPushedFcn = createCallbackFcn(app, @button_addSelectionButtonPushed, true);
            app.button_addSelection.Enable = 'off';
            app.button_addSelection.Text = 'Add Selection >>';
            app.button_deleteMappedValue = uibutton(app.panel_pairFiles, 'push');
            app.button_deleteMappedValue.ButtonPushedFcn = createCallbackFcn(app, @button_deleteMappedValueButtonPushed, true);
            app.button_deleteMappedValue.Enable = 'off';
            app.button_deleteMappedValue.Text = 'Delete Mapped Value';
            app.label_step3 = uilabel(app.panel_pairFiles);
            app.label_step3.Text = '(3)';
            app.button_pairData = uibutton(app.panel_pairFiles, 'push');
            app.button_pairData.ButtonPushedFcn = createCallbackFcn(app, @button_pairDataButtonPushed, true);
            app.button_pairData.Enable = 'off';
            app.button_pairData.Text = 'Pair data';
            app.TrialsLabel = uilabel(app.panel_pairFiles);
            app.TrialsLabel.Text = 'Trials';
            app.listB_trialsAvail = uilistbox(app.panel_pairFiles);
            app.listB_trialsAvail.Items = {};
            app.listB_trialsAvail.Value = {};
            app.button_extractData = uibutton(app.panel_pairFiles, 'push');
            app.button_extractData.ButtonPushedFcn = createCallbackFcn(app, @button_extractDataButtonPushed, true);
            app.button_extractData.Enable = 'off';
            app.button_extractData.Text = 'Extract data';
            app.tab_videoSync = uitab(app.tabg_group);
            app.tab_videoSync.Title = 'Video Sync';
            app.panel_videoSync = uipanel(app.tab_videoSync);
            app.table_trialsMapped = uitable(app.panel_videoSync);
            app.table_trialsMapped.ColumnName = {'Trial'; 'Done'};
            app.table_trialsMapped.RowName = {};
            app.table_trialsMapped.CellSelectionCallback = createCallbackFcn(app, @table_trialsMappedCellSelection, true);
            app.table_trialsMapped.Enable = 'off';
            app.button_popTrials = uibutton(app.panel_videoSync, 'push');
            app.button_popTrials.ButtonPushedFcn = createCallbackFcn(app, @button_popTrialsButtonPushed, true);
            app.button_popTrials.Text = 'Populate Trials';
            app.button_genWave = uibutton(app.panel_videoSync, 'push');
            app.button_genWave.ButtonPushedFcn = createCallbackFcn(app, @button_genWaveButtonPushed, true);
            app.button_genWave.Enable = 'off';
            app.button_genWave.Text = 'Generate Wave';
            app.label_videoFrame = uilabel(app.panel_videoSync);
            app.label_videoFrame.Text = 'Video Frame';
            app.spinner_videoFrame = uispinner(app.panel_videoSync);
            app.spinner_videoFrame.Limits = [0 Inf];
            app.spinner_videoFrame.ValueDisplayFormat = '%.0f';
            app.spinner_videoFrame.Enable = 'off';
            app.uiA_syncSignal = uiaxes(app.panel_videoSync);
            xlabel(app.uiA_syncSignal, 'Time (s)')
            ylabel(app.uiA_syncSignal, 'Norm Amplitude')
            app.uiA_syncSignal.YLim = [0 1.1];
            app.uiA_syncSignal.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1];
            app.uiA_syncSignal.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'; '1.1'};
            app.button_trimToVideo = uibutton(app.panel_videoSync, 'push');
            app.button_trimToVideo.ButtonPushedFcn = createCallbackFcn(app, @button_trimToVideoButtonPushed, true);
            app.button_trimToVideo.Enable = 'off';
            app.button_trimToVideo.Text = 'Trim to Video';
            app.button_doneSync = uibutton(app.panel_videoSync, 'push');
            app.button_doneSync.ButtonPushedFcn = createCallbackFcn(app, @button_doneSyncButtonPushed, true);
            app.button_doneSync.Enable = 'off';
            app.button_doneSync.Text = 'Done';
            app.label_vesperTimeMs = uilabel(app.panel_videoSync);
            app.label_vesperTimeMs.Text = 'Vesper Time (ms)';
            app.editF_vesperTimeMs = uieditfield(app.panel_videoSync, 'numeric');
            app.editF_vesperTimeMs.Limits = [-1000 1000];
            app.editF_vesperTimeMs.ValueDisplayFormat = '%.0f';
            app.editF_vesperTimeMs.ValueChangedFcn = createCallbackFcn(app, @editF_vesperTimeMsValueChanged, true);
            app.editF_vesperTimeMs.Enable = 'off';
            app.label_vesperTime = uilabel(app.panel_videoSync);
            app.label_vesperTime.Text = 'Vesper Time (%)';
            app.slider_vesperTime = uislider(app.panel_videoSync);
            app.slider_vesperTime.Limits = [-100 100];
            app.slider_vesperTime.ValueChangedFcn = createCallbackFcn(app, @slider_vesperTimeValueChanged, true);
            app.slider_vesperTime.Enable = 'off';
            app.chkB_lockTrial = uicheckbox(app.panel_videoSync);
            app.chkB_lockTrial.ValueChangedFcn = createCallbackFcn(app, @chkB_lockTrialValueChanged, true);
            app.chkB_lockTrial.Text = 'Lock Trial';
            app.button_resetAxes = uibutton(app.panel_videoSync, 'push');
            app.button_resetAxes.ButtonPushedFcn = createCallbackFcn(app, @button_resetAxesButtonPushed, true);
            app.button_resetAxes.Enable = 'off';
            app.button_resetAxes.Text = 'reset axes';
            app.tab_filterData = uitab(app.tabg_group);
            app.tab_filterData.Title = 'Filter Data';
            app.panel_filterData = uipanel(app.tab_filterData);
            app.tab_saveAll = uitab(app.tabg_group);
            app.tab_saveAll.Title = 'Save All';
            app.panel_saveAll = uipanel(app.tab_saveAll);
            app.button_saveAllAndExit = uibutton(app.panel_saveAll, 'push');
            app.button_saveAllAndExit.ButtonPushedFcn = createCallbackFcn(app, @button_saveAllAndExitButtonPushed, true);
            app.button_saveAllAndExit.Text = 'SAVE ALL AND EXIT!!!!!!!';
            app.button_copyToWorkspace = uibutton(app.panel_saveAll, 'push');
            app.button_copyToWorkspace.Text = {'Copy selected '; 'to workspace'};
            app.label_emgObj = uilabel(app.panel_saveAll);
            app.label_emgObj.Text = 'Emg Obj Data';
            app.listB_emgObj = uilistbox(app.panel_saveAll);
            app.button_prev = uibutton(app.ui_motionCsvToNodeStdMap, 'push');
            app.button_prev.ButtonPushedFcn = createCallbackFcn(app, @button_prevButtonPushed, true);
            app.button_prev.Enable = 'off';
            app.button_prev.Text = '<<Prev';
            app.button_next = uibutton(app.ui_motionCsvToNodeStdMap, 'push');
            app.button_next.ButtonPushedFcn = createCallbackFcn(app, @button_nextButtonPushed, true);
            app.button_next.Enable = 'off';
            app.button_next.Text = 'Next>>';
            app.label_errorMsg = uilabel(app.ui_motionCsvToNodeStdMap);
            app.label_errorMsg.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = vesperDataImportGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ui_motionCsvToNodeStdMap)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ui_motionCsvToNodeStdMap)
        end
    end
end