classdef videoTrialHandlerGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ui_motionCsvToNodeStdMap  matlab.ui.Figure
        tabg_group                matlab.ui.container.TabGroup
        tab_selectRepo            matlab.ui.container.Tab
        panel_selectRepo          matlab.ui.container.Panel
        button_videoRepoSelect    matlab.ui.control.Button
        chkB_videoRepoLock        matlab.ui.control.CheckBox
        label_globalDirsAvail     matlab.ui.control.Label
        listB_globalDirsAvail     matlab.ui.control.ListBox
        label_videoRepo           matlab.ui.control.Label
        editF_videoRepo           matlab.ui.control.EditField
        label_step1               matlab.ui.control.Label
        button_refreshWorkingDir  matlab.ui.control.Button
        label_step0               matlab.ui.control.Label
        label_workingDir          matlab.ui.control.Label
        label_delim               matlab.ui.control.Label
        editF_delim               matlab.ui.control.EditField
        chkB_delimLock            matlab.ui.control.CheckBox
        button_browse             matlab.ui.control.Button
        label_cameraType          matlab.ui.control.Label
        textA_cameraType          matlab.ui.control.TextArea
        chkB_cameraType           matlab.ui.control.CheckBox
        tab_cameraType            matlab.ui.container.Tab
        panel_cameraType          matlab.ui.container.Panel
        table_filenames           matlab.ui.control.Table
        button_popFilenames       matlab.ui.control.Button
        button_assignTypes        matlab.ui.control.Button
        tab_cameraObj             matlab.ui.container.Tab
        panel_cameraObj           matlab.ui.container.Panel
        button_segmentNames       matlab.ui.control.Button
        label_segmentNamesText    matlab.ui.control.Label
        listB_videoNameSec        matlab.ui.control.ListBox
        table_nameAssign          matlab.ui.control.Table
        label_cameraObj           matlab.ui.control.Label
        listB_cameraObj           matlab.ui.control.ListBox
        button_populateCam        matlab.ui.control.Button
        button_assign             matlab.ui.control.Button
        label_cameraTypes         matlab.ui.control.Label
        dropD_cameraTypes         matlab.ui.control.DropDown
        button_select             matlab.ui.control.Button
        button_assignCamObj       matlab.ui.control.Button
        tab_getTime               matlab.ui.container.Tab
        panel_getTime             matlab.ui.container.Panel
        label_experiment          matlab.ui.control.Label
        date_experiment           matlab.ui.control.DatePicker
        chkB_lockDate             matlab.ui.control.CheckBox
        label_cameraTypes_2       matlab.ui.control.Label
        dropD_cameraTypes2        matlab.ui.control.DropDown
        label_hrsStart            matlab.ui.control.Label
        spinner_hrsStart          matlab.ui.control.Spinner
        label_minStart            matlab.ui.control.Label
        spinner_minStart          matlab.ui.control.Spinner
        label_secStart            matlab.ui.control.Label
        spinner_secStart          matlab.ui.control.Spinner
        label_mscStart            matlab.ui.control.Label
        spinner_mscStart          matlab.ui.control.Spinner
        text_hour                 matlab.ui.control.Label
        text_minute               matlab.ui.control.Label
        text_second               matlab.ui.control.Label
        text_msecond              matlab.ui.control.Label
        text_sampleName           matlab.ui.control.Label
        button_assignStartT       matlab.ui.control.Button
        button_getDuration        matlab.ui.control.Button
        button_doneStartType      matlab.ui.control.Button
        chkB_noMs                 matlab.ui.control.CheckBox
        button_getName            matlab.ui.control.Button
        tab_assignTrial           matlab.ui.container.Tab
        panel_assignTrial         matlab.ui.container.Panel
        table_videos              matlab.ui.control.Table
        button_add                matlab.ui.control.Button
        TrialsLabel               matlab.ui.control.Label
        listB_trialList           matlab.ui.control.ListBox
        button_populateTrials     matlab.ui.control.Button
        tab_saveAll               matlab.ui.container.Tab
        panel_saveAll             matlab.ui.container.Panel
        button_saveAllAndExit     matlab.ui.control.Button
        button_computeData        matlab.ui.control.Button
        button_prev               matlab.ui.control.Button
        button_next               matlab.ui.control.Button
        label_errorMsg            matlab.ui.control.Label
    end


%---------------------------- CONSTANT PROPERTIES ---------------------------------%    
    
    properties (Access = private, Constant = true)
        % variables that control app's visibility workflow function
        isTabChar = 'tab_';
        
        % number of initial steps, pair with startedFlag non constant property
        % change this according to the app's workflow before it unlocks the buton next
        numInitialSteps = 3;
    end
    
    properties (Access = public, Constant = true)
        % app descriptors
        appName = 'videoTrialHandlerGui';
        appVersion = '0.04';
        appAuthor = 'Alberto Bortoni';
        
        % important/ relevant output from this app
        savedVarName = 'videoTrialHandler';
        
        % the full app, required to populate this app with a previous session
        % this contains ALL components, even those stored in savedVarName
        savedAppName = 'videoTrialHandlerGuiApp';
        
        % relevant variables to save in savedVarName
        % savedAppName saves this plus everything else too
        %TODO -- there is no error checking in making sure varsToSaveinStd are all valid names
        varsToSaveinStd =  {'videos'    ;
                            'videoRepo' ;
                            'appVersion'};
        
    end
    

%---------------------------- VARIABLE PROPERTIES --------------------------------%
    
    properties (Access = private)
        % this variable stores the working directory
        workingDir = '';
        
        % started flag to make sure there is name and directory
        startedFlag = 0;
        
        % tab control variable, start at first tab
        tabControl = 1;
        
        % application tabs, used to move around with buttons, computed at startup
        applicationTabs;

        %pointer to segment video name
        cameraObjPtr = struct();
        cameraSegPtr;
        cameraObjs;
        
        %table selections
        assignNamesSelCell;
        videosSelCell;
        
        %cameras
        cameraTypes;
        
        %times
        times = struct();
        date;
    end
         
    properties (Access = public)
        videos = table();
        videoRepo  = '';
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                               METHODS SECTION                                     %
%-----------------------------------------------------------------------------------%
    
    methods (Access = private)
        
        function setInitialGuiParam(app)
            % fist turn all panels off
            % get names of all tabs and indexes in app.
            tnames  = fieldnames(app);
            
            % tabs are in the order that they apear in the gui in sequence
            %TODO -- if tabs have multiple children this wont work
            app.applicationTabs = tnames(contains(tnames,app.isTabChar));
            
            % disable all panels
            for(i=1: length(app.applicationTabs))
                app.(app.applicationTabs{i}).Children.Visible = 'off';
            end
            
            % the first pannel and tab controlled by class tab control
            app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
            app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            
            % load the study directory and display files
            loadGlobalFiles(app);
            loadCameraObjs(app);
        end%setInitialGuiParam
%-----------------------------------------------------------------------------------%
        
        function loadGlobalFiles(app)   
                %TODO -- should trim this to show a ../../dir
                app.label_workingDir.Text = app.workingDir;

                % dont display the trials availiable
                %TODO -- files are proabably remote so ned to work with shurtcuts
                [~, tmp] = displayDirRecursively(fullfile(app.workingDir,'global'));
                app.listB_globalDirsAvail.Items = tmp;
                
        end%loadGlobalFiles
%-----------------------------------------------------------------------------------%

        function loadCameraObjs(app)    
            % see if there is an instance of this app in the working directory
            if(~(isfile(fullfile(app.workingDir, 'share', [createCameraObjGui.savedAppName '.mat']))))
                msgbox({['There is no ' createCameraObjGui.savedAppName ' File in the share Working Directory.'] 'You must create camera obj first'}, 'Error','error');
                
            else
                % try to open the file
                try
                    % just load the camera objects
                    tmp = fullfile(app.workingDir, 'share', [createCameraObjGui.savedAppName '.mat']);
                    tstruct = load(tmp, createCameraObjGui.savedVarName);
                    %TODO -- retrieve camera names with harcoded 1 seems weird
                    app.cameraObjs = tstruct.(createCameraObjGui.savedVarName).(createCameraObjGui.varsToSaveinStd{1});

                % send a message and ask the user to take action 
                catch
                    msgbox({'There was an error loading the file, app wil close'; ...
                            'If probem persist you need to delete the saved mat file'}, 'Error','error');
                    delete(app);
                end
            end
        end%loadCameraObjs
%-----------------------------------------------------------------------------------%

        function incStartFlag(app, cnt)
            % increase or decrease the start flag with bound checking
            % this ommits the value of cnt and only uses its sign.
            if(cnt>0 && app.startedFlag<app.numInitialSteps)
                app.startedFlag = app.startedFlag + 1;
            elseif(cnt<0 && app.startedFlag>0)
                app.startedFlag = app.startedFlag - 1;
            end
            
            % determine if the start is done and enable prev and next buttons
            if(app.startedFlag>=app.numInitialSteps)
                app.button_next.Enable = 'on';
                app.button_prev.Enable = 'on';
            else
                app.button_next.Enable = 'off';
                app.button_prev.Enable = 'off';
            end
        end%incStartFlag
%-----------------------------------------------------------------------------------%
        
        function advanceTab(app, cnt)
            % first check if cnt will request a tab out of range
            if((app.tabControl+cnt)>length(app.applicationTabs) || (app.tabControl+cnt)<1)
                dispMsg(app, 'e', 'tab change request out of range');
            else
                % clear errors if any
                dispMsg(app, 'c');
                
                % disable the current panel
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'off';
                
                % increase tab control and activate next panel and tab
                app.tabControl = app.tabControl+cnt;
                app.(app.applicationTabs{app.tabControl}).Children.Visible = 'on';
                app.tabg_group.SelectedTab = app.(app.applicationTabs{app.tabControl});
            end
        end%advanceTab
%-----------------------------------------------------------------------------------%

        function s = varsToSaveinStdFcn(app)
            % saves just the relevant variables that come out of this class not to save ui elements
            for(i = 1:length(app.varsToSaveinStd))
                s.(app.varsToSaveinStd{i}) = app.(app.varsToSaveinStd{i});
            end                
        end%varsToSaveinStdFcn
%-----------------------------------------------------------------------------------%

        function dispMsg(app, ttype, tmsg)
            % For log messaging blue
            if(strcmpi(ttype, 'L'))
                app.label_errorMsg.Text = ['LOG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.45,0.74];
                
            % for error messages red
            elseif(strcmpi(ttype, 'E'))
                app.label_errorMsg.Text = ['ERROR: ' tmsg];
                app.label_errorMsg.FontColor = [0.64,0.08,0.18];
                
            % general propose message black
            elseif(strcmpi(ttype, 'M'))
                app.label_errorMsg.Text = ['MSG: ' tmsg];
                app.label_errorMsg.FontColor = [0.00,0.00,0.00];
            
            % for clearing the mesage
            elseif(strcmpi(ttype, 'C'))
                app.label_errorMsg.Text = '';
            end
        end%dispMsg
%-----------------------------------------------------------------------------------%

    end%private methods

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tworkingDir)
            app.workingDir = tworkingDir;
            setInitialGuiParam(app);
        end

        % Button pushed function: button_next
        function button_nextButtonPushed(app, event)
            % move to next tab
            advanceTab(app, 1);
        end

        % Button pushed function: button_prev
        function button_prevButtonPushed(app, event)
            % move to previous tab
            advanceTab(app, -1);
        end

        % Value changed function: chkB_videoRepoLock
        function chkB_videoRepoLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_videoRepoLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.button_videoRepoSelect.Enable = 'off';
                app.button_browse.Enable = 'off';
                incStartFlag(app, 1);
            else
                app.button_videoRepoSelect.Enable = 'on';
                app.button_browse.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Button pushed function: button_videoRepoSelect
        function button_videoRepoSelectButtonPushed(app, event)
            % assign value of current selected file to this field
            %TODO -- this needs to be verified that ta csv is actually selected
            % make sure there is a selection
            if(~isempty(app.listB_globalDirsAvail.Value))
                % get the selected files in its extended name form
                app.editF_videoRepo.Value = app.listB_globalDirsAvail.Value;
                app.videoRepo = fullfile(app.workingDir, 'global', app.editF_videoRepo.Value);
                
                % lock the value trough the checkbox
                app.chkB_videoRepoLock.Value = 1;
                chkB_videoRepoLockValueChanged(app);
            
                % clear errors if any
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'Select a file first');
            end 
        end

        % Button pushed function: button_saveAllAndExit
        function button_saveAllAndExitButtonPushed(app, event)
            % disable wargings from saving stuff such as uia components
            %TODO -- just dont try to save them to prevent warning
            warning('off');
            
            % make a struct of what I want to save to that they keep those variable names
            tmp.(app.savedAppName) = app;
            tmp.(app.savedVarName) = varsToSaveinStdFcn(app);
            
            % save file with all information
            %TODO -- does this needs a try catch?
            save(fullfile(app.workingDir, 'share', [app.savedAppName '.mat']), '-struct', 'tmp');
            
            % alert the user it is done
            dispMsg(app, 'l', ['a "' app.savedAppName '" file was created in share with all video resources']);
            loadGlobalFiles(app);
            msgbox({'All Saved!!!';'Science away'},'Success');
            
            % turn warnings back on
            warning('on');
            %TODO -- this is a matlab bug
            warning off MATLAB:subscripting:noSubscriptsSpecified;
        end

        % Button pushed function: button_refreshWorkingDir
        function button_refreshWorkingDirButtonPushed(app, event)
            loadGlobalFiles(app);
        end

        % Value changed function: chkB_delimLock
        function chkB_delimLockValueChanged(app, event)
            % for some reason the var value needs to exsist, Abstract defined?
            value = app.chkB_delimLock.Value;
 
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.editF_delim.Enable = 'off';
                incStartFlag(app, 1);
            else
                app.editF_delim.Enable = 'on';
                incStartFlag(app, -1);
            end
            
        end

        % Button pushed function: button_segmentNames
        function button_segmentNamesButtonPushed(app, event)
            %get the cameras of the selected type
            tvideoidxf = find(contains(app.videos.type, app.dropD_cameraTypes.Value));
            tvideoList = app.videos.filename(tvideoidxf);
            
            %determine the number of segments
            tnumseg = length(strfind(tvideoList{1}, app.editF_delim.Value))+1;
            tsegname = cell(length(tvideoList), tnumseg);        
            %deconstruct all filenames in segments
            for i = 1: length(tvideoList)
                tmp    = strfind(tvideoList{i}, app.editF_delim.Value)';
                tstart = [1;(tmp+1)];
                tend   = [(tmp-1);(strfind(tvideoList{i},'.')-1)];
                for j = 1: tnumseg
                    tsegname{i,j} = tvideoList{i}(tstart(j):tend(j));
                end
            end

            for i = 1: length(tvideoList)
                app.videos.segmented{tvideoidxf(i)} = tsegname(i,:);
            end
            
            %select a random file to use as template
            tranvid = floor(rand()*length(tvideoList))+1;
            
            %add items to the list
            app.listB_videoNameSec.Items = tsegname(tranvid,:)';
            app.listB_videoNameSec.ItemsData = [1: length(app.listB_videoNameSec.Items)];
            
        end

        % Button pushed function: button_populateCam
        function button_populateCamButtonPushed(app, event)
            %disable prev buttons
            app.listB_videoNameSec.Enable  = 'off';
            app.button_segmentNames.Enable = 'off';
            app.button_select.Enable       = 'off';
            app.dropD_cameraTypes.Enable   = 'off';
            
            %get the camera identifier from the different camera types
            app.cameraSegPtr = cell(size(app.videos,1),1);

            tnames = fieldnames(app.cameraObjPtr);
            for i = 1: length(tnames)
                tidx =  contains(app.videos.type, tnames{i});
                app.cameraSegPtr(tidx) = cellfun(@(tvar) tvar{app.cameraObjPtr.(tnames{i})}, app.videos.segmented(tidx),'UniformOutput',false);
            end
            
            %display avail cameras
            app.listB_cameraObj.Items = fields(app.cameraObjs);
            
            %populate all videos with camera obj segment only
            tsegname = unique(app.cameraSegPtr);
            
            %initialize the table empty
            tmp = cell(length(tsegname),1);
            tmp(:) = {''};
            app.table_nameAssign.Data = [tmp, tmp];
            
            %fill with the camera segment, only unique values
            app.table_nameAssign.Data(:,1) = tsegname; 

            %enable add to list
            app.button_assign.Enable = 'on';
        end

        % Button pushed function: button_assign
        function button_assignButtonPushed(app, event)
            %check that there is a target cell and a camera selected
            if(~isempty(app.assignNamesSelCell) && ~isempty(app.listB_cameraObj.Value))
                app.table_nameAssign.Data(app.assignNamesSelCell,2) = cellstr(app.listB_cameraObj.Value);
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'Select camera form list and a target cell in table');
            end
        end

        % Cell selection callback: table_nameAssign
        function table_nameAssignCellSelection(app, event)
            indices = event.Indices;
            %get the user-selected row
            app.assignNamesSelCell = indices(1,1);
        end

        % Button pushed function: button_populateTrials
        function button_populateTrialsButtonPushed(app, event)
            %TODO -- warring comes on, turn off
            %make sure previous table has no empty spaces.
            if(isempty(find(cellfun(@isempty,app.table_nameAssign.Data(:,2)),1)))
                tcameraObj = cell(size(app.videos,1),1);
                tcameraObj(:) = {''}; 
                %first segment all videos with camera selection
                for i = 1: length(app.table_nameAssign.Data(:,1))
                  tidx = contains(app.videos.segmented(:,app.cameraObjPtr),app.table_nameAssign.Data(i,1));
                  tcameraObj(tidx) = app.table_nameAssign.Data(i,2);
                end
                app.videos.cameraObjName = tcameraObj;
                
                %populate the table of all videos with segmented data
                %app.table_videos.Data = app.videos.segmented;
                tmp = cell(size(app.videos.segmented,1),1);
                tmp(:) = {''};
                app.table_videos.Data = [app.videos.segmented, tmp];
                
                %name the columns
                tcol = (1:(size(app.table_videos.Data,2)-1))';
                tcolname = strcat(num2str(tcol),'c');
                tcolname = [cellstr(tcolname)', 'trial'];
                app.table_videos.ColumnName = tcolname;
                    
                %populate the availiable trials
                ttrialDir  = fullfile(app.workingDir, 'trial');
                ttrialList = cellstr(ls(ttrialDir));
                ttrialList = ttrialList(3:end);
                app.listB_trialList.Items = ttrialList;
                
                %clear error
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'all cells in the previous table must be filled');
            end
        end

        % Cell selection callback: table_videos
        function table_videosCellSelection(app, event)
            indices = event.Indices;
            %get the user-selected row
            app.videosSelCell = indices(:,1);
        end

        % Button pushed function: button_add
        function button_addButtonPushed(app, event)
            %check that there is a target cell and a trial selected
            if(~isempty(app.videosSelCell) && ~isempty(app.listB_trialList.Value))
                app.table_videos.Data(app.videosSelCell,end) = cellstr(app.listB_trialList.Value);
                
                %TODO -- put a log mesg to say that to redu you can click populate again
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'Select camera form list and a target cell in table');
            end
        end

        % Button pushed function: button_computeData
        function button_computeDataButtonPushed(app, event)
            % amke sure that all the table is populaed 
            if(isempty(find(cellfun(@isempty,app.table_videos.Data(:,end)),1)))
                 %add trial name to the video list
                 app.videos.trial = app.table_videos.Data(:,end);
                 
                 app.button_saveAllAndExit.Enable = 'on';
                 
                 %clear error
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'all cells in the previous table must be filled');
            end
        end

        % Button pushed function: button_browse
        function button_browsePushed(app, event)
            % browse to video repo
            app.videoRepo = uigetdir(app.workingDir);
            tmp = compressPathNmes(app.videoRepo);
            app.editF_videoRepo.Value = tmp{:};
            
            % lock the value trough the checkbox
            app.chkB_videoRepoLock.Value = 1;
            chkB_videoRepoLockValueChanged(app);
        end

        % Value changed function: chkB_cameraType
        function chkB_cameraTypeValueChanged(app, event)
            value = app.chkB_cameraType.Value;
            % check and uncheck the box enables/disables the button to asign new values
            % increase the value of the start flag, or decrease if the lock is off
            if(value == 1)
                app.textA_cameraType.Enable = 'off';
                app.cameraTypes = app.textA_cameraType.Value';
                app.dropD_cameraTypes.Items  = app.textA_cameraType.Value;
                app.dropD_cameraTypes2.Items = app.textA_cameraType.Value;
                incStartFlag(app, 1);
            else
                app.textA_cameraType.Enable = 'on';
                incStartFlag(app, -1);
            end
        end

        % Button pushed function: button_popFilenames
        function button_popFilenamesButtonPushed(app, event)
            %get a list of all the videos in the repo
            tvideoList = cellstr(ls(app.videoRepo));
            tvideoList = tvideoList(3:end);
            app.videos.filename = tvideoList;
            
            %populate the table
            tmp = cell(length(tvideoList),1);
            tmp(:) = {'pick'};
            app.table_filenames.Data = [tvideoList, tmp];
            app.table_filenames.ColumnEditable = [false true];
            app.table_filenames.ColumnFormat = ({[] app.cameraTypes});
            
            %enable button
            app.button_assignTypes.Enable = 'on';
        end

        % Button pushed function: button_assignTypes
        function button_assignTypesButtonPushed(app, event)
            if(~any(contains(app.table_filenames.Data(:,2), 'pick')))
                %assign the type and place it in the final table
                app.videos.type = app.table_filenames.Data(:,2);
                app.videos.segmented = cell(size(app.videos,1),1);
                app.button_popFilenames.Enable = 'off';
                
                %clear error
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'all cameras need to be assigned a type');
            end
        end

        % Button pushed function: button_select
        function button_selectPushed(app, event)
            %make sure a value is selected
            if(~isempty(app.listB_videoNameSec.Value))
                %get the index of the segment that is camera obj
                app.cameraObjPtr.(app.dropD_cameraTypes.Value) = app.listB_videoNameSec.Value;
                
                if(length(fieldnames(app.cameraObjPtr)) == length(app.dropD_cameraTypes.Items))
                    app.button_populateCam.Enable  = 'on';
                end
                %remove items from the list
                app.listB_videoNameSec.Items = {};
                dispMsg(app, 'l', 'done! Now select annother camera');
                
                %clear error
                dispMsg(app, 'c');
            else
                dispMsg(app, 'e', 'need to select a type from the box');
            end
        end

        % Value changed function: dropD_cameraTypes
        function dropD_cameraTypesValueChanged(app, event)
            value = app.dropD_cameraTypes.Value;
            dispMsg(app, 'c');
        end

        % Button pushed function: button_assignCamObj
        function button_assignCamObjButtonPushed(app, event)
            %make sure previous table has no empty spaces.
            if(isempty(find(cellfun(@isempty,app.table_nameAssign.Data(:,2)),1)))
                %dissable buttons
                app.button_populateCam.Enable = 'off';
                app.button_assign.Enable      = 'off';
                
                tcameraObj = cell(size(app.videos,1),1);
                tcameraObj(:) = {''}; 
                %first segment all videos with camera selection
                for i = 1: length(app.table_nameAssign.Data(:,1))
                  tidx = contains(app.cameraSegPtr,app.table_nameAssign.Data(i,1));
                  tcameraObj(tidx) = app.table_nameAssign.Data(i,2);
                end
    
                app.videos.cameraObjName = tcameraObj;
                assignin('base','vid', app.videos);
                
                %clear error
                dispMsg(app, 'l', 'Camera assigned! move to timings');
            else
                dispMsg(app, 'e', 'all cells in the previous table must be filled');
            end
        end

        % Value changed function: chkB_lockDate
        function chkB_lockDateValueChanged(app, event)
            value = app.chkB_lockDate.Value;
            if(value == 1)
                app.date = app.date_experiment.Value;
                app.date_experiment.Enable = 'off';
                app.button_getName.Enable  = 'on'; 
            else
                app.date_experiment.Enable = 'on';
                app.button_getName.Enable  = 'off';
            end
        end

        % Value changed function: dropD_cameraTypes2
        function dropD_cameraTypes2ValueChanged(app, event)
            value = app.dropD_cameraTypes2.Value;
            dispMsg(app, 'c');
        end

        % Button pushed function: button_getName
        function button_getNameButtonPushed(app, event)
            %get the cameras of the selected type
            tvideoidxf = find(contains(app.videos.type, app.dropD_cameraTypes2.Value));
            app.text_sampleName.Text = app.videos.filename(tvideoidxf(1));
            
            %set the limits on the spinners
            tmp = size(app.text_sampleName.Text{:},2);
            app.spinner_hrsStart.Limits = [1, tmp-1];
            app.spinner_minStart.Limits = [1, tmp-1];
            app.spinner_secStart.Limits = [1, tmp-1];
            app.spinner_mscStart.Limits = [1, tmp-2];
            
            %display the text
            app.text_hour.Text    = app.text_sampleName.Text{:}(app.spinner_hrsStart.Value:app.spinner_hrsStart.Value+1);
            app.text_minute.Text  = app.text_sampleName.Text{:}(app.spinner_minStart.Value:app.spinner_minStart.Value+1);
            app.text_second.Text  = app.text_sampleName.Text{:}(app.spinner_secStart.Value:app.spinner_secStart.Value+1);
            app.text_msecond.Text = app.text_sampleName.Text{:}(app.spinner_mscStart.Value:app.spinner_mscStart.Value+2);
            
            dispMsg(app, 'c');
        end

        % Value changed function: chkB_noMs
        function chkB_noMsValueChanged(app, event)
            value = app.chkB_noMs.Value;
            if(value == 1)
                app.spinner_mscStart.Enable = 'off';
            else
                app.spinner_mscStart.Enable = 'on';
            end
        end

        % Value changed function: spinner_hrsStart
        function spinner_hrsStartValueChanged(app, event)
            value = app.spinner_hrsStart.Value;
            if(~strcmp(app.text_sampleName.Text{:},''))
                app.text_hour.Text    = app.text_sampleName.Text{:}(app.spinner_hrsStart.Value:app.spinner_hrsStart.Value+1);
            end
        end

        % Value changed function: spinner_minStart
        function spinner_minStartValueChanged(app, event)
            value = app.spinner_minStart.Value;
            if(~strcmp(app.text_sampleName.Text{:},''))
                app.text_minute.Text  = app.text_sampleName.Text{:}(app.spinner_minStart.Value:app.spinner_minStart.Value+1);
            end
        end

        % Value changed function: spinner_secStart
        function spinner_secStartValueChanged(app, event)
            value = app.spinner_secStart.Value;
            if(~strcmp(app.text_sampleName.Text{:},''))
                app.text_second.Text  = app.text_sampleName.Text{:}(app.spinner_secStart.Value:app.spinner_secStart.Value+1);
            end
        end

        % Value changed function: spinner_mscStart
        function spinner_mscStartValueChanged(app, event)
            value = app.spinner_mscStart.Value;
            if(~strcmp(app.text_sampleName.Text{:},''))
                app.text_msecond.Text = app.text_sampleName.Text{:}(app.spinner_mscStart.Value:app.spinner_mscStart.Value+2);
            end
        end

        % Button pushed function: button_doneStartType
        function button_doneStartTypeButtonPushed(app, event)
            %extract the values of time for this camera type
            app.times.(app.dropD_cameraTypes2.Value) = [app.spinner_hrsStart.Value, app.spinner_hrsStart.Value+1; ...
                                                        app.spinner_minStart.Value, app.spinner_minStart.Value+1; ...
                                                        app.spinner_secStart.Value, app.spinner_secStart.Value+1; ...
                                                        app.spinner_mscStart.Value, app.spinner_mscStart.Value+2];
                                                   
            %clear everything
            app.spinner_hrsStart.Value = 1;
            app.spinner_minStart.Value = 1;
            app.spinner_secStart.Value = 1;
            app.spinner_mscStart.Value = 1;
            app.text_hour.Text    = {''};
            app.text_minute.Text  = {''};
            app.text_second.Text  = {''};
            app.text_msecond.Text = {''};
            app.text_sampleName.Text = {''};
            
            if(length(fieldnames(app.times)) == length(app.dropD_cameraTypes2.Items))
                app.button_populateCam.Enable  = 'on';
                dispMsg(app, 'c');
            else
                dispMsg(app, 'l', 'Select a new camera type');
            end
        end

        % Button pushed function: button_assignStartT
        function button_assignStartTPushed(app, event)
            %get the cameras of the selected type
            
            
            %get the camera identifier from the different camera types
            tstart    = NaT(size(app.videos,1),1);
            ttypes    = fieldnames(app.times);
            
            %sweep trough all camera types
            for(j = 1: length(ttypes))
                tlimits = app.times.(ttypes{j});
                tvideoidxf = find(contains(app.videos.type, ttypes{j}));
                
                for i = 1: length(tvideoidxf)
                    tfile = app.videos.filename{tvideoidxf(i)};
                    
                    thh = str2num(tfile(tlimits(1,1):tlimits(1,2)));
                    tmm = str2num(tfile(tlimits(2,1):tlimits(2,2)));
                    tss = str2num(tfile(tlimits(3,1):tlimits(3,2)));
                    if(app.chkB_noMs.Value ==1)
                        tms = 0;
                    else
                        tms = str2num(tfile(tlimits(4,1):tlimits(4,2)));
                    end
                    
                    tstart(tvideoidxf(i))    = datetime(app.date.Year, app.date.Month, app.date.Day, thh, tmm, tss+(tms/1000));
                end
            end
            app.videos.startDate = tstart;
                
            app.button_getDuration.Enable = 'on';
            dispMsg(app, 'c');
        end

        % Button pushed function: button_getDuration
        function button_getDurationPushed(app, event)
            %read video info and get the duration
            tduration  = zeros(size(app.videos,1),1);
            tframerate = zeros(size(app.videos,1),1);
            dispMsg(app, 'l', 'Reading videos. This will take a minute');
            
            %open videos and get metadata
            for(i = 1: size(app.videos,1))
%                 tmp = VideoReader(fullfile(app.workingDir, app.videos.filename{i}));
                tmp = VideoReader('C:\Users\alberto-bortoni\Desktop\sandbox\carolliaVid\videoLight.mp4');
                tduration(i)  = tmp.Duration;
                tframerate(i) = tmp.FrameRate;
            end
            
            dispMsg(app, 'l', 'done!');
            app.videos.duration  = tduration;
            app.videos.frameRate = tframerate;
            assignin('base','vid',app.videos);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ui_motionCsvToNodeStdMap = uifigure;
            app.ui_motionCsvToNodeStdMap.Name = 'Motion CSV Data To Node Standard Mapping';
            app.tabg_group = uitabgroup(app.ui_motionCsvToNodeStdMap);
            app.tab_selectRepo = uitab(app.tabg_group);
            app.tab_selectRepo.Title = 'Select Repo';
            app.panel_selectRepo = uipanel(app.tab_selectRepo);
            app.button_videoRepoSelect = uibutton(app.panel_selectRepo, 'push');
            app.button_videoRepoSelect.ButtonPushedFcn = createCallbackFcn(app, @button_videoRepoSelectButtonPushed, true);
            app.button_videoRepoSelect.Text = '<<';
            app.chkB_videoRepoLock = uicheckbox(app.panel_selectRepo);
            app.chkB_videoRepoLock.ValueChangedFcn = createCallbackFcn(app, @chkB_videoRepoLockValueChanged, true);
            app.chkB_videoRepoLock.Text = 'Lock';
            app.label_globalDirsAvail = uilabel(app.panel_selectRepo);
            app.label_globalDirsAvail.Text = 'Displaying files in /global';
            app.listB_globalDirsAvail = uilistbox(app.panel_selectRepo);
            app.listB_globalDirsAvail.Items = {};
            app.listB_globalDirsAvail.Value = {};
            app.label_videoRepo = uilabel(app.panel_selectRepo);
            app.label_videoRepo.Text = 'Video Repository';
            app.editF_videoRepo = uieditfield(app.panel_selectRepo, 'text');
            app.editF_videoRepo.Editable = 'off';
            app.label_step1 = uilabel(app.panel_selectRepo);
            app.label_step1.Text = '(1)';
            app.button_refreshWorkingDir = uibutton(app.panel_selectRepo, 'push');
            app.button_refreshWorkingDir.ButtonPushedFcn = createCallbackFcn(app, @button_refreshWorkingDirButtonPushed, true);
            app.button_refreshWorkingDir.Text = 'Refresh';
            app.label_step0 = uilabel(app.panel_selectRepo);
            app.label_step0.Text = '(0)';
            app.label_workingDir = uilabel(app.panel_selectRepo);
            app.label_workingDir.Text = '';
            app.label_delim = uilabel(app.panel_selectRepo);
            app.label_delim.Text = 'Delimiter';
            app.editF_delim = uieditfield(app.panel_selectRepo, 'text');
            app.editF_delim.Value = '_';
            app.chkB_delimLock = uicheckbox(app.panel_selectRepo);
            app.chkB_delimLock.ValueChangedFcn = createCallbackFcn(app, @chkB_delimLockValueChanged, true);
            app.chkB_delimLock.Text = 'Lock';
            app.button_browse = uibutton(app.panel_selectRepo, 'push');
            app.button_browse.ButtonPushedFcn = createCallbackFcn(app, @button_browsePushed, true);
            app.button_browse.Text = 'browse';
            app.label_cameraType = uilabel(app.panel_selectRepo);
            app.label_cameraType.Text = 'Camera Types';
            app.textA_cameraType = uitextarea(app.panel_selectRepo);
            app.chkB_cameraType = uicheckbox(app.panel_selectRepo);
            app.chkB_cameraType.ValueChangedFcn = createCallbackFcn(app, @chkB_cameraTypeValueChanged, true);
            app.chkB_cameraType.Text = 'Lock';
            app.tab_cameraType = uitab(app.tabg_group);
            app.tab_cameraType.Title = 'Camera Type';
            app.panel_cameraType = uipanel(app.tab_cameraType);
            app.table_filenames = uitable(app.panel_cameraType);
            app.table_filenames.ColumnName = {'Filename'; 'Type'};
            app.table_filenames.RowName = {};
            app.button_popFilenames = uibutton(app.panel_cameraType, 'push');
            app.button_popFilenames.ButtonPushedFcn = createCallbackFcn(app, @button_popFilenamesButtonPushed, true);
            app.button_popFilenames.Text = 'Populate Filenames';
            app.button_assignTypes = uibutton(app.panel_cameraType, 'push');
            app.button_assignTypes.ButtonPushedFcn = createCallbackFcn(app, @button_assignTypesButtonPushed, true);
            app.button_assignTypes.Enable = 'off';
            app.button_assignTypes.Text = 'Assign Types';
            app.tab_cameraObj = uitab(app.tabg_group);
            app.tab_cameraObj.Title = 'Camera Obj';
            app.panel_cameraObj = uipanel(app.tab_cameraObj);
            app.button_segmentNames = uibutton(app.panel_cameraObj, 'push');
            app.button_segmentNames.ButtonPushedFcn = createCallbackFcn(app, @button_segmentNamesButtonPushed, true);
            app.button_segmentNames.Text = 'Segment Video Names';
            app.label_segmentNamesText = uilabel(app.panel_cameraObj);
            app.label_segmentNamesText.Text = 'Select which refers to the camera position';
            app.listB_videoNameSec = uilistbox(app.panel_cameraObj);
            app.listB_videoNameSec.Items = {};
            app.listB_videoNameSec.Value = {};
            app.table_nameAssign = uitable(app.panel_cameraObj);
            app.table_nameAssign.ColumnName = {'Video Seg'; 'Camera Obj'};
            app.table_nameAssign.RowName = {};
            app.table_nameAssign.CellSelectionCallback = createCallbackFcn(app, @table_nameAssignCellSelection, true);
            app.label_cameraObj = uilabel(app.panel_cameraObj);
            app.label_cameraObj.Text = 'Camera Objects';
            app.listB_cameraObj = uilistbox(app.panel_cameraObj);
            app.listB_cameraObj.Items = {};
            app.listB_cameraObj.Value = {};
            app.button_populateCam = uibutton(app.panel_cameraObj, 'push');
            app.button_populateCam.ButtonPushedFcn = createCallbackFcn(app, @button_populateCamButtonPushed, true);
            app.button_populateCam.Enable = 'off';
            app.button_populateCam.Text = 'Populate Cameras';
            app.button_assign = uibutton(app.panel_cameraObj, 'push');
            app.button_assign.ButtonPushedFcn = createCallbackFcn(app, @button_assignButtonPushed, true);
            app.button_assign.Enable = 'off';
            app.button_assign.Text = '<<';
            app.label_cameraTypes = uilabel(app.panel_cameraObj);
            app.label_cameraTypes.Text = 'Camera Types';
            app.dropD_cameraTypes = uidropdown(app.panel_cameraObj);
            app.dropD_cameraTypes.Items = {};
            app.dropD_cameraTypes.ValueChangedFcn = createCallbackFcn(app, @dropD_cameraTypesValueChanged, true);
            app.dropD_cameraTypes.Value = {};
            app.button_select = uibutton(app.panel_cameraObj, 'push');
            app.button_select.ButtonPushedFcn = createCallbackFcn(app, @button_selectPushed, true);
            app.button_select.Text = 'select';
            app.button_assignCamObj = uibutton(app.panel_cameraObj, 'push');
            app.button_assignCamObj.ButtonPushedFcn = createCallbackFcn(app, @button_assignCamObjButtonPushed, true);
            app.button_assignCamObj.Text = 'Assign camera obj';
            app.tab_getTime = uitab(app.tabg_group);
            app.tab_getTime.Title = 'Get Time';
            app.panel_getTime = uipanel(app.tab_getTime);
            app.label_experiment = uilabel(app.panel_getTime);
            app.label_experiment.Text = 'Experiment date';
            app.date_experiment = uidatepicker(app.panel_getTime);
            app.date_experiment.DisplayFormat = 'uuuu-MM-dd';
            app.chkB_lockDate = uicheckbox(app.panel_getTime);
            app.chkB_lockDate.ValueChangedFcn = createCallbackFcn(app, @chkB_lockDateValueChanged, true);
            app.chkB_lockDate.Text = 'Lock';
            app.label_cameraTypes_2 = uilabel(app.panel_getTime);
            app.label_cameraTypes_2.Text = 'Camera Types';
            app.dropD_cameraTypes2 = uidropdown(app.panel_getTime);
            app.dropD_cameraTypes2.Items = {};
            app.dropD_cameraTypes2.ValueChangedFcn = createCallbackFcn(app, @dropD_cameraTypes2ValueChanged, true);
            app.dropD_cameraTypes2.Value = {};
            app.label_hrsStart = uilabel(app.panel_getTime);
            app.label_hrsStart.Text = 'hour';
            app.spinner_hrsStart = uispinner(app.panel_getTime);
            app.spinner_hrsStart.Limits = [1 Inf];
            app.spinner_hrsStart.ValueChangedFcn = createCallbackFcn(app, @spinner_hrsStartValueChanged, true);
            app.spinner_hrsStart.Value = 1;
            app.label_minStart = uilabel(app.panel_getTime);
            app.label_minStart.Text = 'minute';
            app.spinner_minStart = uispinner(app.panel_getTime);
            app.spinner_minStart.Limits = [1 Inf];
            app.spinner_minStart.ValueChangedFcn = createCallbackFcn(app, @spinner_minStartValueChanged, true);
            app.spinner_minStart.Value = 1;
            app.label_secStart = uilabel(app.panel_getTime);
            app.label_secStart.Text = 'second';
            app.spinner_secStart = uispinner(app.panel_getTime);
            app.spinner_secStart.Limits = [1 Inf];
            app.spinner_secStart.ValueChangedFcn = createCallbackFcn(app, @spinner_secStartValueChanged, true);
            app.spinner_secStart.Value = 1;
            app.label_mscStart = uilabel(app.panel_getTime);
            app.label_mscStart.Text = 'milisecond';
            app.spinner_mscStart = uispinner(app.panel_getTime);
            app.spinner_mscStart.Limits = [1 Inf];
            app.spinner_mscStart.ValueChangedFcn = createCallbackFcn(app, @spinner_mscStartValueChanged, true);
            app.spinner_mscStart.Value = 1;
            app.text_hour = uilabel(app.panel_getTime);
            app.text_hour.Text = '';
            app.text_minute = uilabel(app.panel_getTime);
            app.text_minute.Text = '';
            app.text_second = uilabel(app.panel_getTime);
            app.text_second.Text = '';
            app.text_msecond = uilabel(app.panel_getTime);
            app.text_msecond.Text = '';
            app.text_sampleName = uilabel(app.panel_getTime);
            app.text_sampleName.Text = '';
            app.button_assignStartT = uibutton(app.panel_getTime, 'push');
            app.button_assignStartT.ButtonPushedFcn = createCallbackFcn(app, @button_assignStartTPushed, true);
            app.button_assignStartT.Text = 'Assign Start Times';
            app.button_getDuration = uibutton(app.panel_getTime, 'push');
            app.button_getDuration.ButtonPushedFcn = createCallbackFcn(app, @button_getDurationPushed, true);
            app.button_getDuration.Enable = 'off';
            app.button_getDuration.Text = 'Get videoDuration';
            app.button_doneStartType = uibutton(app.panel_getTime, 'push');
            app.button_doneStartType.ButtonPushedFcn = createCallbackFcn(app, @button_doneStartTypeButtonPushed, true);
            app.button_doneStartType.Text = 'done';
            app.button_getName = uibutton(app.panel_getTime, 'push');
            app.button_getName.ButtonPushedFcn = createCallbackFcn(app, @button_getNameButtonPushed, true);
            app.button_getName.Enable = 'off';
            app.button_getName.Text = 'Get Name';
            app.chkB_noMs = uicheckbox(app.panel_getTime);
            app.chkB_noMs.ValueChangedFcn = createCallbackFcn(app, @chkB_noMsValueChanged, true);
            app.chkB_noMs.Text = 'no ms';
            app.tab_assignTrial = uitab(app.tabg_group);
            app.tab_assignTrial.Title = 'Assign Trial';
            app.panel_assignTrial = uipanel(app.tab_assignTrial);
            app.table_videos = uitable(app.panel_assignTrial);
            app.table_videos.ColumnName = {''};
            app.table_videos.RowName = {};
            app.table_videos.CellSelectionCallback = createCallbackFcn(app, @table_videosCellSelection, true);
            app.button_add = uibutton(app.panel_assignTrial, 'push');
            app.button_add.ButtonPushedFcn = createCallbackFcn(app, @button_addButtonPushed, true);
            app.button_add.Text = '<<';
            app.TrialsLabel = uilabel(app.panel_assignTrial);
            app.TrialsLabel.Text = 'Trials';
            app.listB_trialList = uilistbox(app.panel_assignTrial);
            app.listB_trialList.Items = {};
            app.listB_trialList.Value = {};
            app.button_populateTrials = uibutton(app.panel_assignTrial, 'push');
            app.button_populateTrials.ButtonPushedFcn = createCallbackFcn(app, @button_populateTrialsButtonPushed, true);
            app.button_populateTrials.Text = 'Populate Trials';
            app.tab_saveAll = uitab(app.tabg_group);
            app.tab_saveAll.Title = 'Save All';
            app.panel_saveAll = uipanel(app.tab_saveAll);
            app.button_saveAllAndExit = uibutton(app.panel_saveAll, 'push');
            app.button_saveAllAndExit.ButtonPushedFcn = createCallbackFcn(app, @button_saveAllAndExitButtonPushed, true);
            app.button_saveAllAndExit.Enable = 'off';
            app.button_saveAllAndExit.Text = 'SAVE ALL AND EXIT!!!!!!!';
            app.button_computeData = uibutton(app.panel_saveAll, 'push');
            app.button_computeData.ButtonPushedFcn = createCallbackFcn(app, @button_computeDataButtonPushed, true);
            app.button_computeData.Text = 'Compute all data';
            app.button_prev = uibutton(app.ui_motionCsvToNodeStdMap, 'push');
            app.button_prev.ButtonPushedFcn = createCallbackFcn(app, @button_prevButtonPushed, true);
            app.button_prev.Enable = 'off';
            app.button_prev.Text = '<<Prev';
            app.button_next = uibutton(app.ui_motionCsvToNodeStdMap, 'push');
            app.button_next.ButtonPushedFcn = createCallbackFcn(app, @button_nextButtonPushed, true);
            app.button_next.Enable = 'off';
            app.button_next.Text = 'Next>>';
            app.label_errorMsg = uilabel(app.ui_motionCsvToNodeStdMap);
            app.label_errorMsg.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = videoTrialHandlerGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ui_motionCsvToNodeStdMap)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ui_motionCsvToNodeStdMap)
        end
    end
end