classdef quickEmgPlotGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FilesAvailiableListBoxLabel  matlab.ui.control.Label
        filesAvail                   matlab.ui.control.ListBox
        refresh                      matlab.ui.control.Button
        plotch1                      matlab.ui.control.Button
        plotch2                      matlab.ui.control.Button
        changeDir                    matlab.ui.control.Button
        gEditFieldLabel              matlab.ui.control.Label
        gainch1                      matlab.ui.control.NumericEditField
        gEditField_2Label            matlab.ui.control.Label
        gainch2                      matlab.ui.control.NumericEditField
        labelDir                     matlab.ui.control.Label
        scale                        matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        workingDir;
        preGain = 10;
        
        freq = 2000;
        
        ch1gain;
        ch2gain;
        
        vref = 2.42;
        resolution = 2^16;
        
        emgraw;
        ch1data;
        ch2data;
        time;
        
    end
    
    methods (Access = private)
        
        function readEmgFile(app)
            app.emgraw  = readtable(fullfile(app.workingDir, app.filesAvail.Value), 'ReadVariableNames', true);
            app.time    = linspace(0, size(app.emgraw,1)/app.freq, size(app.emgraw,1));
            
            if(app.scale.Value)
                app.ch1data = app.emgraw.CH1*app.vref/app.resolution;
                app.ch2data = app.emgraw.CH2*app.vref/app.resolution;
            else
                app.ch1data = app.emgraw.CH1;
                app.ch2data = app.emgraw.CH2;
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.workingDir = 'C:\Users\alberto-bortoni\Desktop';
            app.labelDir.Text = app.workingDir;
            
            tfiles = cellstr(ls(app.workingDir));
            tfiles = tfiles(3:end);
            
            app.filesAvail.Items = tfiles;
        end

        % Button pushed function: changeDir
        function changeDirPushed(app, event)
           app.workingDir = uigetdir('C:\Users\alberto-bortoni\Desktop');
           app.labelDir.Text = app.workingDir;
           refreshPushed(app);
        end

        % Button pushed function: refresh
        function refreshPushed(app, event)
            tfiles = cellstr(ls(app.workingDir));
            tfiles = tfiles(3:end);
            
            app.filesAvail.Items = tfiles;
        end

        % Button pushed function: plotch1
        function plotch1Pushed(app, event)
            readEmgFile(app);
            
            plot(app.time, app.ch1data);
            tax = gca;
            
            if(app.scale.Value)
                tax.YLimMode = 'manual';
                tax.YLim = [-2.42, 2.42];
            else
                tax.YLimMode = 'auto';
            end
        end

        % Button pushed function: plotch2
        function plotch2Pushed(app, event)
            readEmgFile(app);
            
            plot(app.time, app.ch2data);
            tax = gca;
            
            if(app.scale.Value)
                tax.YLimMode = 'manual';
                tax.YLim = [-2.42, 2.42];
            else
                tax.YLimMode = 'auto';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FilesAvailiableListBoxLabel = uilabel(app.UIFigure);
            app.FilesAvailiableListBoxLabel.Text = 'Files Availiable';
            app.filesAvail = uilistbox(app.UIFigure);
            app.filesAvail.Items = {};
            app.filesAvail.Value = {};
            app.refresh = uibutton(app.UIFigure, 'push');
            app.refresh.ButtonPushedFcn = createCallbackFcn(app, @refreshPushed, true);
            app.refresh.Text = 'Refresh';
            app.plotch1 = uibutton(app.UIFigure, 'push');
            app.plotch1.ButtonPushedFcn = createCallbackFcn(app, @plotch1Pushed, true);
            app.plotch1.Text = 'Plot Ch1';
            app.plotch2 = uibutton(app.UIFigure, 'push');
            app.plotch2.ButtonPushedFcn = createCallbackFcn(app, @plotch2Pushed, true);
            app.plotch2.Text = 'Plot Ch2';
            app.changeDir = uibutton(app.UIFigure, 'push');
            app.changeDir.ButtonPushedFcn = createCallbackFcn(app, @changeDirPushed, true);
            app.changeDir.Text = 'Change Dir';
            app.gEditFieldLabel = uilabel(app.UIFigure);
            app.gEditFieldLabel.Text = 'g';
            app.gainch1 = uieditfield(app.UIFigure, 'numeric');
            app.gainch1.Limits = [0 12];
            app.gainch1.Value = 6;
            app.gEditField_2Label = uilabel(app.UIFigure);
            app.gEditField_2Label.Text = 'g';
            app.gainch2 = uieditfield(app.UIFigure, 'numeric');
            app.gainch2.Limits = [0 12];
            app.gainch2.Value = 6;
            app.labelDir = uilabel(app.UIFigure);
            app.labelDir.Text = '';
            app.scale = uicheckbox(app.UIFigure);
            app.scale.Text = 'Scale';
        end
    end

    methods (Access = public)

        % Construct app
        function app = quickEmgPlotGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end